classdef AddHyperparameterPopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AddHyperparameterPanel       matlab.ui.container.Panel
        RangeLabel                   matlab.ui.control.Label
        ToEditField                  matlab.ui.control.NumericEditField
        ToEditFieldLabel             matlab.ui.control.Label
        FromEditField                matlab.ui.control.NumericEditField
        FromEditFieldLabel           matlab.ui.control.Label
        HyperparameterDropDown       matlab.ui.control.DropDown
        HyperparameterDropDownLabel  matlab.ui.control.Label
        AddButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % The app that called this app
        models
    end
    
    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, models, modelType)
            app.callingApp = mainApp;
            app.models = models;

            dropDownItems = [];

            if length(app.models) > 1
                if isfield(app.models(1).options, 'hyperparameters')
                    dataFields = {};
                    trainingFields = {};
                    if isfield(app.models(1).options.hyperparameters, 'data')
                        dataFields = fieldnames(app.models(1).options.hyperparameters.data);
                    end
                    if isfield(app.models(1).options.hyperparameters, 'training')
                        trainingFields = fieldnames(app.models(1).options.hyperparameters.training);
                    end

                    for i = 1:length(dataFields)
                        dropDownItems = [dropDownItems string(dataFields{i})];
                    end
                    for i = 1:length(trainingFields)
                        dropDownItems = [dropDownItems string(trainingFields{i})];
                    end
                end
            else
                if isfield(app.models.options, 'hyperparameters')
                    modelFields = {};
                    dataFields = {};
                    trainingFields = {};
                    if isfield(app.models.options.hyperparameters, 'model')
                        modelFields = fieldnames(app.models.options.hyperparameters.model);
                    end
                    if isfield(app.models.options.hyperparameters, 'data')
                        dataFields = fieldnames(app.models.options.hyperparameters.data);
                    end
                    if isfield(app.models.options.hyperparameters, 'training')
                        trainingFields = fieldnames(app.models.options.hyperparameters.training);
                    end
        
                    for i = 1:length(modelFields)
                        dropDownItems = [dropDownItems string(modelFields{i})];
                    end
                    for i = 1:length(dataFields)
                        dropDownItems = [dropDownItems string(dataFields{i})];
                    end
                    for i = 1:length(trainingFields)
                        dropDownItems = [dropDownItems string(trainingFields{i})];
                    end
                end
            end

            if isempty(dropDownItems)
                uialert(app.UIFigure, 'No hyperparameters found for optimization', 'Parameter Error', Icon='error');
                return;
            end
            app.HyperparameterDropDown.Items = dropDownItems;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            name = app.HyperparameterDropDown.Value;
            if isempty(name)
                uialert(app.UIFigure, 'No hyperparameters found for optimization', 'Parameter Error', Icon='error');
                return;
            end
            value = [app.FromEditField.Value app.ToEditField.Value];
            type = 'integer';
            if isfield(app.models.options.hyperparameters, 'model')
                if isfield(app.models.options.hyperparameters.model, name)
                    type = app.models.options.hyperparameters.model.(name).type;
                end
            end
            if isfield(app.models.options.hyperparameters, 'data')
                if isfield(app.models.options.hyperparameters.data, name)
                    type = app.models.options.hyperparameters.data.(name).type;
                end
            end
            if isfield(app.models.options.hyperparameters, 'training')
                if isfield(app.models.options.hyperparameters.training, name)
                    type = app.models.options.hyperparameters.training.(name).type;
                end
            end

            app.callingApp.addHyperparameter(name, value, type);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddHyperparameterPanel = uipanel(app.UIFigure);
            app.AddButton = uibutton(app.AddHyperparameterPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.HyperparameterDropDownLabel = uilabel(app.AddHyperparameterPanel);
            app.HyperparameterDropDownLabel.Text = 'Hyperparameter:';
            app.HyperparameterDropDown = uidropdown(app.AddHyperparameterPanel);
            app.HyperparameterDropDown.Items = {};
            app.HyperparameterDropDown.Value = {};
            app.FromEditFieldLabel = uilabel(app.AddHyperparameterPanel);
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.AddHyperparameterPanel, 'numeric');
            app.FromEditField.Limits = [0 Inf];
            app.FromEditField.Value = 10;
            app.ToEditFieldLabel = uilabel(app.AddHyperparameterPanel);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.AddHyperparameterPanel, 'numeric');
            app.ToEditField.Limits = [0 Inf];
            app.ToEditField.Value = 100;
            app.RangeLabel = uilabel(app.AddHyperparameterPanel);
            app.RangeLabel.Text = 'Range:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddHyperparameterPopup(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end