classdef DynamicThresholdSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MainPanel                     matlab.ui.container.Panel
        ZRangeEditField               matlab.ui.control.NumericEditField
        ZRangeEditFieldLabel          matlab.ui.control.Label
        AnomalyPaddingEditField       matlab.ui.control.NumericEditField
        AnomalyPaddingEditFieldLabel  matlab.ui.control.Label
        WindowSizeEditField           matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel      matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        dynamicSettings
    end
    
    methods (Access = private)
        function checkSettingsChanged(app)
            flag = false;
            if app.WindowSizeEditField.Value ~= app.dynamicSettings.windowSize
                flag = true;
            end
            if app.AnomalyPaddingEditField.Value ~= app.dynamicSettings.anomalyPadding
                flag = true;
            end
            if app.ZRangeEditField.Value ~= app.dynamicSettings.zRange
                flag = true;
            end

            if flag
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end
        end
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, dynamicSettings)
            app.callingApp = mainApp;
            app.dynamicSettings = dynamicSettings;
            app.WindowSizeEditField.Value = dynamicSettings.windowSize;
            app.AnomalyPaddingEditField.Value = dynamicSettings.anomalyPadding;
            app.ZRangeEditField.Value = dynamicSettings.zRange;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            newDynamicSettings.windowSize = app.WindowSizeEditField.Value;
            newDynamicSettings.anomalyPadding = app.AnomalyPaddingEditField.Value;
            newDynamicSettings.zRange = app.ZRangeEditField.Value;
            newDynamicSettings.minPercent = 1;

            app.callingApp.updateDynamicThresholdSettings(newDynamicSettings);
            app.dynamicSettings = newDynamicSettings;
            app.SaveButton.Enable = 'off';
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            app.checkSettingsChanged();
        end

        % Value changed function: AnomalyPaddingEditField
        function AnomalyPaddingEditFieldValueChanged(app, event)
            app.checkSettingsChanged();
        end

        % Value changed function: ZRangeEditField
        function ZRangeEditFieldValueChanged(app, event)
            app.checkSettingsChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainPanel = uipanel(app.UIFigure);
            app.SaveButton = uibutton(app.MainPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.WindowSizeEditFieldLabel = uilabel(app.MainPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size (%):';
            app.WindowSizeEditField = uieditfield(app.MainPanel, 'numeric');
            app.WindowSizeEditField.Limits = [0 50];
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.AnomalyPaddingEditFieldLabel = uilabel(app.MainPanel);
            app.AnomalyPaddingEditFieldLabel.Text = 'Anomaly Padding:';
            app.AnomalyPaddingEditField = uieditfield(app.MainPanel, 'numeric');
            app.AnomalyPaddingEditField.Limits = [0 1000];
            app.AnomalyPaddingEditField.ValueChangedFcn = createCallbackFcn(app, @AnomalyPaddingEditFieldValueChanged, true);
            app.ZRangeEditFieldLabel = uilabel(app.MainPanel);
            app.ZRangeEditFieldLabel.Text = 'Z-Range:';
            app.ZRangeEditField = uieditfield(app.MainPanel, 'numeric');
            app.ZRangeEditField.Limits = [1 10];
            app.ZRangeEditField.ValueChangedFcn = createCallbackFcn(app, @ZRangeEditFieldValueChanged, true);
            app.ZRangeEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DynamicThresholdSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end