classdef ModelSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        SingleModelTab                  matlab.ui.container.Tab
        SingleModelPanel                matlab.ui.container.Panel
        SM_ExitButton                   matlab.ui.control.Button
        SM_AddToModelSelectionButton    matlab.ui.control.Button
        SM_ModelTypePanel               matlab.ui.container.Panel
        SM_ModelTypeButtonGroup         matlab.ui.container.ButtonGroup
        SM_PredictiveButton             matlab.ui.control.ToggleButton
        SM_ReconstructiveButton         matlab.ui.control.ToggleButton
        SM_ModelSelectionDropDown       matlab.ui.control.DropDown
        ModelLabel                      matlab.ui.control.Label
        SM_ModelHyperparametersPanel    matlab.ui.container.Panel
        SM_ModelHyperparameter_2_Label  matlab.ui.control.Label
        SM_ModelHyperparameter_1_Label  matlab.ui.control.Label
        SM_ModelHyperparameter_2_EditField  matlab.ui.control.NumericEditField
        SM_ModelHyperparameter_1_EditField  matlab.ui.control.NumericEditField
        SM_DataAndTrainingHyperparametersPanel  matlab.ui.container.Panel
        SM_RatioTrainValLabel           matlab.ui.control.Label
        SM_RatioEditField               matlab.ui.control.NumericEditField
        SM_MinibatchSizeEditField       matlab.ui.control.NumericEditField
        MinibatchSizeLabel              matlab.ui.control.Label
        SM_EpochsEditField              matlab.ui.control.NumericEditField
        EpochsEditField_2Label          matlab.ui.control.Label
        SM_StepSizeEditField            matlab.ui.control.NumericEditField
        StepsizeLabel                   matlab.ui.control.Label
        SM_WindowSizeEditField          matlab.ui.control.NumericEditField
        WindowsizeLabel                 matlab.ui.control.Label
        SM_UseValDataCheckBox           matlab.ui.control.CheckBox
        MultipleModelsTab               matlab.ui.container.Tab
        MultipleModelsPanel             matlab.ui.container.Panel
        MM_ExitButton                   matlab.ui.control.Button
        MM_AddToModelSelectionButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % The app that called this app
    end
    
    methods (Access = private)
        
        function showModelHyperparametersFor(app, model)
            switch model
                case 'Basic R'
                    app.SM_ModelHyperparameter_1_EditField.Value = 32;
                    app.SM_ModelHyperparameter_1_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_1_Label.Text = 'Neurons';
                    app.SM_ModelHyperparameter_1_Label.Visible = 'on';
                    app.SM_ModelHyperparameter_2_EditField.Visible = 'off';
                    app.SM_ModelHyperparameter_2_Label.Visible = 'off';
                    app.SM_EpochsEditField.Value = 35;
                    app.SM_MinibatchSizeEditField.Value = 70;
                case 'LSTM R'
                    app.SM_ModelHyperparameter_1_EditField.Value = 80;
                    app.SM_ModelHyperparameter_1_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.SM_ModelHyperparameter_1_Label.Visible = 'on';
                    app.SM_ModelHyperparameter_2_EditField.Visible = 'off';
                    app.SM_ModelHyperparameter_2_Label.Visible = 'off';
                    app.SM_EpochsEditField.Value = 35;
                    app.SM_MinibatchSizeEditField.Value = 70;
                case 'Hybrid CNN-LSTM R'
                    app.SM_ModelHyperparameter_1_EditField.Value = 50;
                    app.SM_ModelHyperparameter_1_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.SM_ModelHyperparameter_1_Label.Visible = 'on';
                    app.SM_ModelHyperparameter_2_EditField.Value = 32;
                    app.SM_ModelHyperparameter_2_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_2_Label.Visible = 'on';
                    app.SM_EpochsEditField.Value = 35;
                    app.SM_MinibatchSizeEditField.Value = 70;
                case 'LSTM'
                    app.SM_ModelHyperparameter_1_EditField.Value = 80;
                    app.SM_ModelHyperparameter_1_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.SM_ModelHyperparameter_1_Label.Visible = 'on';
                    app.SM_ModelHyperparameter_2_EditField.Visible = 'off';
                    app.SM_ModelHyperparameter_2_Label.Visible = 'off';
                    app.SM_EpochsEditField.Value = 35;
                    app.SM_MinibatchSizeEditField.Value = 70;
                case 'Hybrid CNN-LSTM'
                    app.SM_ModelHyperparameter_1_EditField.Value = 50;
                    app.SM_ModelHyperparameter_1_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.SM_ModelHyperparameter_1_Label.Visible = 'on';
                    app.SM_ModelHyperparameter_2_EditField.Value = 32;
                    app.SM_ModelHyperparameter_2_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_2_Label.Visible = 'on';
                    app.SM_EpochsEditField.Value = 35;
                    app.SM_MinibatchSizeEditField.Value = 70;
                case 'GRU'
                    app.SM_ModelHyperparameter_1_EditField.Value = 80;
                    app.SM_ModelHyperparameter_1_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.SM_ModelHyperparameter_1_Label.Visible = 'on';
                    app.SM_ModelHyperparameter_2_EditField.Visible = 'off';
                    app.SM_ModelHyperparameter_2_Label.Visible = 'off';
                    app.SM_EpochsEditField.Value = 35;
                    app.SM_MinibatchSizeEditField.Value = 70;
                case 'CNN (DeepAnT)'
                    app.SM_ModelHyperparameter_1_EditField.Value = 32;
                    app.SM_ModelHyperparameter_1_EditField.Visible = 'on';
                    app.SM_ModelHyperparameter_1_Label.Text = 'Filter';
                    app.SM_ModelHyperparameter_1_Label.Visible = 'on';
                    app.SM_EpochsEditField.Value = 35;
                    app.SM_MinibatchSizeEditField.Value = 70;                
            end
        end
        
        function resetHyperparameters(app)
            app.SM_ModelHyperparameter_1_EditField.Value = 32;
            app.SM_ModelHyperparameter_2_EditField.Value = 32;
            app.SM_WindowSizeEditField.Value = 35;
            app.SM_StepSizeEditField.Value = 1;
            app.SM_EpochsEditField.Value = 35;
            app.SM_MinibatchSizeEditField.Value = 70;
            app.SM_RatioEditField.Value = 0.8;
            app.SM_UseValDataCheckBox.Value = false;
            app.SM_RatioEditField.Enable = 'off';
            app.SM_RatioTrainValLabel.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            movegui(app.UIFigure,"center")
            app.callingApp = mainApp;
        end

        % Button pushed function: SM_AddToModelSelectionButton
        function SM_AddToModelSelectionButtonPushed(app, event)
            % TODO: catch false user inputs
            
            networkOptions.model = app.SM_ModelSelectionDropDown.Value;

            switch app.SM_ModelTypeButtonGroup.SelectedObject
                case app.SM_ReconstructiveButton
                    networkOptions.modelType = 'Reconstructive';
                    switch app.SM_ModelSelectionDropDown.Value
                        case 'Basic R'  
                            networkOptions.neurons = app.SM_ModelHyperparameter_1_EditField.Value;
                            networkOptions.hiddenUnits = 0;
                            networkOptions.filter = 0;
                        case 'LSTM R'
                            networkOptions.hiddenUnits = app.SM_ModelHyperparameter_1_EditField.Value;
                            networkOptions.neurons = 0;
                            networkOptions.filter = 0;
                        case 'Hybrid CNN-LSTM R'
                            networkOptions.hiddenUnits = app.SM_ModelHyperparameter_1_EditField.Value;
                            networkOptions.filter = app.SM_ModelHyperparameter_2_EditField.Value;
                            networkOptions.neurons = 0;
                    end
                case app.SM_PredictiveButton
                    networkOptions.modelType = 'Predictive';
                    switch app.SM_ModelSelectionDropDown.Value
                        case 'LSTM'
                            networkOptions.hiddenUnits = app.SM_ModelHyperparameter_1_EditField.Value;
                            networkOptions.neurons = 0;
                            networkOptions.filter = 0;
                        case 'Hybrid CNN-LSTM'
                            networkOptions.hiddenUnits = app.SM_ModelHyperparameter_1_EditField.Value;
                            networkOptions.filter = app.SM_ModelHyperparameter_2_EditField.Value;
                            networkOptions.neurons = 0;
                        case 'GRU'
                            networkOptions.hiddenUnits = app.SM_ModelHyperparameter_1_EditField.Value;
                            networkOptions.neurons = 0;
                            networkOptions.filter = 0;
                        case 'CNN (DeepAnT)'
                            networkOptions.hiddenUnits = 0;
                            networkOptions.filter = app.SM_ModelHyperparameter_1_EditField.Value;
                            networkOptions.neurons = 0;
                    end
            end
            
            networkOptions.windowSize = app.SM_WindowSizeEditField.Value;
            networkOptions.stepSize = app.SM_StepSizeEditField.Value;
            networkOptions.epochs = app.SM_EpochsEditField.Value;
            networkOptions.minibatchSize = app.SM_MinibatchSizeEditField.Value;

            if app.SM_UseValDataCheckBox.Value
                networkOptions.ratioTrainVal = app.SM_RatioEditField.Value;
            else
                networkOptions.ratioTrainVal = 1;
            end

            
            app.callingApp.addNetwork(networkOptions)
        end

        % Button pushed function: SM_ExitButton
        function SM_ExitButtonPushed(app, event)
            app.delete()
        end

        % Selection changed function: SM_ModelTypeButtonGroup
        function SM_ModelTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SM_ModelTypeButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.SM_ReconstructiveButton
                    app.SM_ModelSelectionDropDown.Items = ["Basic R", "LSTM R", "Hybrid CNN-LSTM R"];
                    app.SM_ModelSelectionDropDown.Value = "Basic R";
                    app.resetHyperparameters();
                    app.showModelHyperparametersFor('Basic R');
                case app.SM_PredictiveButton
                    app.SM_ModelSelectionDropDown.Items = ["LSTM", "Hybrid CNN-LSTM", "GRU", "CNN (DeepAnT)"];
                    app.SM_ModelSelectionDropDown.Value = "LSTM";
                    app.resetHyperparameters();
                    app.showModelHyperparametersFor('LSTM');
            end
        end

        % Value changed function: SM_ModelSelectionDropDown
        function SM_ModelSelectionDropDownValueChanged(app, event)
            value = app.SM_ModelSelectionDropDown.Value;

            app.resetHyperparameters();
            app.showModelHyperparametersFor(value);
        end

        % Value changed function: SM_UseValDataCheckBox
        function SM_UseValDataCheckBoxValueChanged(app, event)
            value = app.SM_UseValDataCheckBox.Value;
            if value
                app.SM_RatioEditField.Enable = 'on';
                app.SM_RatioTrainValLabel.Enable = 'on';
            else
                app.SM_RatioEditField.Enable = 'off';
                app.SM_RatioTrainValLabel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.SingleModelTab = uitab(app.MainTabGroup);
            app.SingleModelTab.Title = 'Single Model';
            app.SingleModelPanel = uipanel(app.SingleModelTab);
            app.SM_DataAndTrainingHyperparametersPanel = uipanel(app.SingleModelPanel);
            app.SM_DataAndTrainingHyperparametersPanel.Title = 'Data and Training Hyperparameters';
            app.SM_UseValDataCheckBox = uicheckbox(app.SM_DataAndTrainingHyperparametersPanel);
            app.SM_UseValDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SM_UseValDataCheckBoxValueChanged, true);
            app.SM_UseValDataCheckBox.Text = 'Use Val Data';
            app.WindowsizeLabel = uilabel(app.SM_DataAndTrainingHyperparametersPanel);
            app.WindowsizeLabel.Text = 'Window Size';
            app.SM_WindowSizeEditField = uieditfield(app.SM_DataAndTrainingHyperparametersPanel, 'numeric');
            app.SM_WindowSizeEditField.Limits = [1 Inf];
            app.SM_WindowSizeEditField.Value = 35;
            app.StepsizeLabel = uilabel(app.SM_DataAndTrainingHyperparametersPanel);
            app.StepsizeLabel.Text = 'Step Size';
            app.SM_StepSizeEditField = uieditfield(app.SM_DataAndTrainingHyperparametersPanel, 'numeric');
            app.SM_StepSizeEditField.Limits = [1 Inf];
            app.SM_StepSizeEditField.Value = 1;
            app.EpochsEditField_2Label = uilabel(app.SM_DataAndTrainingHyperparametersPanel);
            app.EpochsEditField_2Label.Text = 'Epochs';
            app.SM_EpochsEditField = uieditfield(app.SM_DataAndTrainingHyperparametersPanel, 'numeric');
            app.SM_EpochsEditField.Limits = [1 Inf];
            app.SM_EpochsEditField.Value = 35;
            app.MinibatchSizeLabel = uilabel(app.SM_DataAndTrainingHyperparametersPanel);
            app.MinibatchSizeLabel.Text = 'Minibatch Size';
            app.SM_MinibatchSizeEditField = uieditfield(app.SM_DataAndTrainingHyperparametersPanel, 'numeric');
            app.SM_MinibatchSizeEditField.Limits = [1 Inf];
            app.SM_MinibatchSizeEditField.Value = 70;
            app.SM_RatioEditField = uieditfield(app.SM_DataAndTrainingHyperparametersPanel, 'numeric');
            app.SM_RatioEditField.Limits = [0 1];
            app.SM_RatioEditField.Enable = 'off';
            app.SM_RatioEditField.Value = 0.8;
            app.SM_RatioTrainValLabel = uilabel(app.SM_DataAndTrainingHyperparametersPanel);
            app.SM_RatioTrainValLabel.Enable = 'off';
            app.SM_RatioTrainValLabel.Text = 'Ratio (Train / Val)';
            app.SM_ModelHyperparametersPanel = uipanel(app.SingleModelPanel);
            app.SM_ModelHyperparametersPanel.Title = 'Model Hyperparameters';
            app.SM_ModelHyperparameter_1_EditField = uieditfield(app.SM_ModelHyperparametersPanel, 'numeric');
            app.SM_ModelHyperparameter_1_EditField.Limits = [1 Inf];
            app.SM_ModelHyperparameter_1_EditField.Value = 32;
            app.SM_ModelHyperparameter_2_EditField = uieditfield(app.SM_ModelHyperparametersPanel, 'numeric');
            app.SM_ModelHyperparameter_2_EditField.Limits = [1 Inf];
            app.SM_ModelHyperparameter_2_EditField.Visible = 'off';
            app.SM_ModelHyperparameter_2_EditField.Value = 32;
            app.SM_ModelHyperparameter_1_Label = uilabel(app.SM_ModelHyperparametersPanel);
            app.SM_ModelHyperparameter_1_Label.Text = 'Neurons';
            app.SM_ModelHyperparameter_2_Label = uilabel(app.SM_ModelHyperparametersPanel);
            app.SM_ModelHyperparameter_2_Label.Visible = 'off';
            app.SM_ModelHyperparameter_2_Label.Text = 'Filter';
            app.SM_ModelTypePanel = uipanel(app.SingleModelPanel);
            app.SM_ModelTypePanel.Title = 'Model Type';
            app.ModelLabel = uilabel(app.SM_ModelTypePanel);
            app.ModelLabel.Text = 'Model';
            app.SM_ModelSelectionDropDown = uidropdown(app.SM_ModelTypePanel);
            app.SM_ModelSelectionDropDown.Items = {'Basic R', 'LSTM R', 'Hybrid CNN-LSTM R'};
            app.SM_ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SM_ModelSelectionDropDownValueChanged, true);
            app.SM_ModelSelectionDropDown.Value = 'Basic R';
            app.SM_ModelTypeButtonGroup = uibuttongroup(app.SM_ModelTypePanel);
            app.SM_ModelTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SM_ModelTypeButtonGroupSelectionChanged, true);
            app.SM_ReconstructiveButton = uitogglebutton(app.SM_ModelTypeButtonGroup);
            app.SM_ReconstructiveButton.Text = 'Reconstructive';
            app.SM_ReconstructiveButton.Value = true;
            app.SM_PredictiveButton = uitogglebutton(app.SM_ModelTypeButtonGroup);
            app.SM_PredictiveButton.Text = 'Predictive';
            app.SM_AddToModelSelectionButton = uibutton(app.SingleModelPanel, 'push');
            app.SM_AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SM_AddToModelSelectionButtonPushed, true);
            app.SM_AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.SM_ExitButton = uibutton(app.SingleModelPanel, 'push');
            app.SM_ExitButton.ButtonPushedFcn = createCallbackFcn(app, @SM_ExitButtonPushed, true);
            app.SM_ExitButton.Text = 'Exit';
            app.MultipleModelsTab = uitab(app.MainTabGroup);
            app.MultipleModelsTab.Title = 'Multiple Models';
            app.MultipleModelsPanel = uipanel(app.MultipleModelsTab);
            app.MM_AddToModelSelectionButton = uibutton(app.MultipleModelsPanel, 'push');
            app.MM_AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.MM_ExitButton = uibutton(app.MultipleModelsPanel, 'push');
            app.MM_ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end