classdef ModelSelection_CML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AddModelPanel              matlab.ui.container.Panel
        AddToModelSelectionButton  matlab.ui.control.Button
        ModelTypePanel             matlab.ui.container.Panel
        ModelSelectionDropDown     matlab.ui.control.DropDown
        ModelLabel                 matlab.ui.control.Label
        ModelHyperparametersPanel  matlab.ui.container.Panel
        ModelParameterLabel_4      matlab.ui.control.Label
        ModelParameterEditField_4  matlab.ui.control.NumericEditField
        ModelParameterLabel_3      matlab.ui.control.Label
        ModelParameterEditField_3  matlab.ui.control.NumericEditField
        ModelParameterLabel_2      matlab.ui.control.Label
        ModelParameterLabel_1      matlab.ui.control.Label
        ModelParameterEditField_2  matlab.ui.control.NumericEditField
        ModelParameterEditField_1  matlab.ui.control.NumericEditField
        DataAndTrainingHyperparametersPanel  matlab.ui.container.Panel
        RatioTrainTrainValLabel    matlab.ui.control.Label
        RatioEditField             matlab.ui.control.NumericEditField
        StepSizeEditField          matlab.ui.control.NumericEditField
        StepsizeLabel              matlab.ui.control.Label
        WindowSizeEditField        matlab.ui.control.NumericEditField
        WindowsizeLabel            matlab.ui.control.Label
        UseValDataCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callingApp % The app that called this app
    end
    
    methods (Access = private)
        
        function showHyperparametersFor(app, model)
            switch model
                case 'OC-SVM'
                case 'iForest'
                case 'LOF'
                    app.ModelParameterEditField_1.Value = 10;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'k';
                    app.ModelParameterLabel_1.Visible = 'on';
                case 'LDOF'
                    app.ModelParameterEditField_1.Value = 10;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'k';
                    app.ModelParameterLabel_1.Visible = 'on';
                case 'ABOD'
                case 'Merlin'
                    app.ModelParameterEditField_1.Value = 20;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'minL';
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.ModelParameterEditField_2.Value = 100;
                    app.ModelParameterEditField_2.Visible = 'on';
                    app.ModelParameterLabel_2.Text = 'maxL';
                    app.ModelParameterLabel_2.Visible = 'on';
            end
        end
        
        function resetHyperparameters(app)
            app.ModelParameterEditField_1.Value = 0;
            app.ModelParameterEditField_2.Value = 0;
            app.ModelParameterEditField_3.Value = 0;
            app.ModelParameterEditField_4.Value = 0;
            app.ModelParameterEditField_1.Visible = 'off';
            app.ModelParameterEditField_2.Visible = 'off';
            app.ModelParameterEditField_3.Visible = 'off';
            app.ModelParameterEditField_4.Visible = 'off';
            app.ModelParameterLabel_1.Text = '';
            app.ModelParameterLabel_2.Text = '';
            app.ModelParameterLabel_3.Text = '';
            app.ModelParameterLabel_4.Text = '';
            app.ModelParameterLabel_1.Visible = 'off';
            app.ModelParameterLabel_2.Visible = 'off';
            app.ModelParameterLabel_3.Visible = 'off';
            app.ModelParameterLabel_4.Visible = 'off';
            app.WindowSizeEditField.Value = 40;
            app.StepSizeEditField.Value = 1;
            app.RatioEditField.Value = 0.8;
            app.UseValDataCheckBox.Value = false;
            app.UseValDataCheckBox.Visible = 'off';
            app.RatioEditField.Enable = 'off';
            app.RatioTrainTrainValLabel.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: AddToModelSelectionButton
        function AddToModelSelectionButtonPushed(app, event)
            options.type = 'CML';
            options.model = app.ModelSelectionDropDown.Value;

            options.trainOnAnomalousData = false;

            switch app.ModelSelectionDropDown.Value
                case 'OC-SVM'
                case 'iForest'
                    options.trainOnAnomalousData = true;
                case 'ABOD'
                case 'LOF'
                    options.hyperparameters.model.k.value = app.ModelParameterEditField_1.Value;
                    options.hyperparameters.model.k.type = 'integer';
                case 'LDOF'
                    options.hyperparameters.model.k.value = app.ModelParameterEditField_1.Value;
                    options.hyperparameters.model.k.type = 'integer';
                case 'Merlin'
                    options.hyperparameters.model.minL.value = app.ModelParameterEditField_1.Value;
                    options.hyperparameters.model.minL.type = 'integer';
                    options.hyperparameters.model.maxL.value = app.ModelParameterEditField_2.Value;
                    options.hyperparameters.model.maxL.type = 'integer';
            end
                        
            options.hyperparameters.data.windowSize.value = app.WindowSizeEditField.Value;
            options.hyperparameters.data.windowSize.type = 'integer';
            options.hyperparameters.data.stepSize.value = app.StepSizeEditField.Value;
            options.hyperparameters.data.stepSize.type = 'integer';

            if app.UseValDataCheckBox.Value
                options.hyperparameters.training.ratioTrainVal.value = app.RatioEditField.Value;
                options.hyperparameters.training.ratioTrainVal.type = 'real';
            else
                options.hyperparameters.training.ratioTrainVal.value = 1;
                options.hyperparameters.training.ratioTrainVal.type = 'real';
            end
            
            app.callingApp.addModel_CML(options);
        end

        % Value changed function: ModelSelectionDropDown
        function ModelSelectionDropDownValueChanged(app, event)
            value = app.ModelSelectionDropDown.Value;

            app.resetHyperparameters();
            app.showHyperparametersFor(value);
        end

        % Value changed function: UseValDataCheckBox
        function UseValDataCheckBoxValueChanged(app, event)
            value = app.UseValDataCheckBox.Value;
            if value
                app.RatioEditField.Enable = 'on';
                app.RatioTrainTrainValLabel.Enable = 'on';
            else
                app.RatioEditField.Enable = 'off';
                app.RatioTrainTrainValLabel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddModelPanel = uipanel(app.UIFigure);
            app.DataAndTrainingHyperparametersPanel = uipanel(app.AddModelPanel);
            app.DataAndTrainingHyperparametersPanel.Title = 'Data and Training Hyperparameters';
            app.UseValDataCheckBox = uicheckbox(app.DataAndTrainingHyperparametersPanel);
            app.UseValDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseValDataCheckBoxValueChanged, true);
            app.UseValDataCheckBox.Visible = 'off';
            app.UseValDataCheckBox.Text = 'Use Val Data';
            app.WindowsizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.WindowsizeLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.Value = 40;
            app.StepsizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.StepsizeLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.Value = 1;
            app.RatioEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.RatioEditField.Limits = [0 1];
            app.RatioEditField.Enable = 'off';
            app.RatioEditField.Visible = 'off';
            app.RatioEditField.Value = 0.8;
            app.RatioTrainTrainValLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.RatioTrainTrainValLabel.Enable = 'off';
            app.RatioTrainTrainValLabel.Visible = 'off';
            app.RatioTrainTrainValLabel.Text = 'Ratio Train/(Train+Val)';
            app.ModelHyperparametersPanel = uipanel(app.AddModelPanel);
            app.ModelHyperparametersPanel.Title = 'Model Hyperparameters';
            app.ModelParameterEditField_1 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_1.Limits = [0 Inf];
            app.ModelParameterEditField_1.Visible = 'off';
            app.ModelParameterEditField_2 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_2.Limits = [0 Inf];
            app.ModelParameterEditField_2.Visible = 'off';
            app.ModelParameterLabel_1 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_1.Text = '';
            app.ModelParameterLabel_2 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_2.Visible = 'off';
            app.ModelParameterLabel_2.Text = '';
            app.ModelParameterEditField_3 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_3.Limits = [0 Inf];
            app.ModelParameterEditField_3.Visible = 'off';
            app.ModelParameterLabel_3 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_3.Text = '';
            app.ModelParameterEditField_4 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_4.Limits = [0 Inf];
            app.ModelParameterEditField_4.Visible = 'off';
            app.ModelParameterLabel_4 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_4.Visible = 'off';
            app.ModelParameterLabel_4.Text = '';
            app.ModelTypePanel = uipanel(app.AddModelPanel);
            app.ModelTypePanel.Title = 'Model Type';
            app.ModelLabel = uilabel(app.ModelTypePanel);
            app.ModelLabel.Text = 'Model';
            app.ModelSelectionDropDown = uidropdown(app.ModelTypePanel);
            app.ModelSelectionDropDown.Items = {'iForest', 'OC-SVM', 'LOF', 'ABOD', 'LDOF', 'Merlin'};
            app.ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSelectionDropDownValueChanged, true);
            app.ModelSelectionDropDown.Value = 'iForest';
            app.AddToModelSelectionButton = uibutton(app.AddModelPanel, 'push');
            app.AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddToModelSelectionButtonPushed, true);
            app.AddToModelSelectionButton.Text = 'Add to Model Selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection_CML(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end