classdef ModelSelection_S < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UseSubsequencesCheckBox    matlab.ui.control.CheckBox
        AddModelPanel              matlab.ui.container.Panel
        IsMultivariateCheckBox     matlab.ui.control.CheckBox
        ModelSelectionDropDown     matlab.ui.control.DropDown
        ModelLabel                 matlab.ui.control.Label
        AddToModelSelectionButton  matlab.ui.control.Button
        ModelHyperparametersPanel  matlab.ui.container.Panel
        ModelParameterLabel_4      matlab.ui.control.Label
        ModelParameterEditField_4  matlab.ui.control.NumericEditField
        ModelParameterLabel_3      matlab.ui.control.Label
        ModelParameterEditField_3  matlab.ui.control.NumericEditField
        ModelParameterLabel_2      matlab.ui.control.Label
        ModelParameterLabel_1      matlab.ui.control.Label
        ModelParameterEditField_2  matlab.ui.control.NumericEditField
        ModelParameterEditField_1  matlab.ui.control.NumericEditField
        DataAndTrainingHyperparametersPanel  matlab.ui.container.Panel
        StepSizeEditField          matlab.ui.control.NumericEditField
        WindowSizeEditField        matlab.ui.control.NumericEditField
        StepSizeLabel              matlab.ui.control.Label
        WindowSizeLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp % The app that called this app
    end
    
    methods (Access = private)
        
        function showHyperparametersFor(app, model)
            % Displays the necessary edit fields for the selected model
            switch model
                case 'Grubbs test'
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterEditField_1.Value = 0.5;
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'alpha';
                    app.UseSubsequencesCheckBox.Visible = 'on';
                    if app.UseSubsequencesCheckBox.Value
                        app.WindowSizeEditField.Visible = 'on';
                        app.WindowSizeLabel.Visible = 'on';
                    end
                    app.IsMultivariateCheckBox.Visible = 'on';
                case 'OD_wpca'
                    app.ModelParameterEditField_1.Value = 0.1;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'ratioOversample';
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.UseSubsequencesCheckBox.Visible = 'on';
                    if app.UseSubsequencesCheckBox.Value
                        app.WindowSizeEditField.Visible = 'on';
                        app.WindowSizeLabel.Visible = 'on';
                    end
                    app.IsMultivariateCheckBox.Visible = 'on';
            end
        end
        
        function resetHyperparameters(app)
            app.ModelParameterEditField_1.Visible = 'off';
            app.ModelParameterEditField_2.Visible = 'off';
            app.ModelParameterEditField_3.Visible = 'off';
            app.ModelParameterEditField_4.Visible = 'off';
            app.ModelParameterLabel_1.Text = '';
            app.ModelParameterLabel_2.Text = '';
            app.ModelParameterLabel_3.Text = '';
            app.ModelParameterLabel_4.Text = '';
            app.ModelParameterLabel_1.Visible = 'off';
            app.ModelParameterLabel_2.Visible = 'off';
            app.ModelParameterLabel_3.Visible = 'off';
            app.ModelParameterLabel_4.Visible = 'off';
            app.UseSubsequencesCheckBox.Visible = 'off';
            app.WindowSizeEditField.Visible = 'off';
            app.WindowSizeLabel.Visible = 'off';
            app.StepSizeEditField.Visible = 'off';
            app.StepSizeLabel.Visible = 'off';
            app.IsMultivariateCheckBox.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: AddToModelSelectionButton
        function AddToModelSelectionButtonPushed(app, event)
            % Creates the modelOptions struct with all necessary modelOptions to
            % identify the configured model
            
            modelOptions.type = 'S';
            modelOptions.name = app.ModelSelectionDropDown.Value;
            

            switch app.ModelSelectionDropDown.Value
                case 'Grubbs test'
                    modelOptions.dataType = 1;
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = false;
                    modelOptions.outputsLabels = true;

                    modelOptions.hyperparameters.alpha.value = app.ModelParameterEditField_1.Value;
                    modelOptions.hyperparameters.alpha.type = 'real';
                    if app.UseSubsequencesCheckBox.Value
                        modelOptions.useSubsequences = true;
                        modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                        modelOptions.hyperparameters.windowSize.type = 'integer';
                    else
                        modelOptions.useSubsequences = false;
                    end
                case 'OD_wpca'
                    modelOptions.dataType = 1;
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = false;
                    modelOptions.outputsLabels = false;
                    
                    modelOptions.hyperparameters.ratioOversample.value = app.ModelParameterEditField_1.Value;
                    modelOptions.hyperparameters.ratioOversample.type = 'real';
                    if app.UseSubsequencesCheckBox.Value
                        modelOptions.useSubsequences = true;
                        modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                        modelOptions.hyperparameters.windowSize.type = 'integer';
                    else
                        modelOptions.useSubsequences = false;
                    end
            end
            
            app.callingApp.addModelToList(modelOptions);
        end

        % Value changed function: ModelSelectionDropDown
        function ModelSelectionDropDownValueChanged(app, event)
            value = app.ModelSelectionDropDown.Value;

            app.resetHyperparameters();
            app.showHyperparametersFor(value);
        end

        % Value changed function: UseSubsequencesCheckBox
        function UseSubsequencesCheckBoxValueChanged(app, event)
           if app.UseSubsequencesCheckBox.Value
               app.WindowSizeEditField.Visible = 'on';
               app.WindowSizeLabel.Visible = 'on';
           else
               app.WindowSizeEditField.Visible = 'off';
               app.WindowSizeLabel.Visible = 'off';
           end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddModelPanel = uipanel(app.UIFigure);
            app.DataAndTrainingHyperparametersPanel = uipanel(app.AddModelPanel);
            app.DataAndTrainingHyperparametersPanel.Title = 'Data and Training Hyperparameters';
            app.WindowSizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.WindowSizeLabel.Visible = 'off';
            app.WindowSizeLabel.Text = 'Window Size';
            app.StepSizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.StepSizeLabel.Visible = 'off';
            app.StepSizeLabel.Text = 'Step Size';
            app.WindowSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.Visible = 'off';
            app.WindowSizeEditField.Value = 100;
            app.StepSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.Visible = 'off';
            app.StepSizeEditField.Value = 1;
            app.ModelHyperparametersPanel = uipanel(app.AddModelPanel);
            app.ModelHyperparametersPanel.Title = 'Model Hyperparameters';
            app.ModelParameterEditField_1 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_1.Limits = [0 Inf];
            app.ModelParameterEditField_1.Value = 0.5;
            app.ModelParameterEditField_2 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_2.Limits = [0 Inf];
            app.ModelParameterEditField_2.Visible = 'off';
            app.ModelParameterLabel_1 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_1.Text = 'alpha';
            app.ModelParameterLabel_2 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_2.Visible = 'off';
            app.ModelParameterLabel_2.Text = '';
            app.ModelParameterEditField_3 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_3.Limits = [0 Inf];
            app.ModelParameterEditField_3.Visible = 'off';
            app.ModelParameterLabel_3 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_3.Visible = 'off';
            app.ModelParameterLabel_3.Text = '';
            app.ModelParameterEditField_4 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_4.Limits = [0 Inf];
            app.ModelParameterEditField_4.Visible = 'off';
            app.ModelParameterLabel_4 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_4.Visible = 'off';
            app.ModelParameterLabel_4.Text = '';
            app.AddToModelSelectionButton = uibutton(app.AddModelPanel, 'push');
            app.AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddToModelSelectionButtonPushed, true);
            app.AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.ModelLabel = uilabel(app.AddModelPanel);
            app.ModelLabel.Text = 'Model';
            app.ModelSelectionDropDown = uidropdown(app.AddModelPanel);
            app.ModelSelectionDropDown.Items = {'Grubbs test', 'OD_wpca'};
            app.ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSelectionDropDownValueChanged, true);
            app.ModelSelectionDropDown.Value = 'Grubbs test';
            app.IsMultivariateCheckBox = uicheckbox(app.AddModelPanel);
            app.IsMultivariateCheckBox.Tooltip = {'If unchecked, a separate model will be trained for each channel of the dataset (If the dataset is multivariate, otherwise this button doesn''t do anything)'};
            app.IsMultivariateCheckBox.Text = 'Is Multivariate';
            app.IsMultivariateCheckBox.Value = true;
            app.UseSubsequencesCheckBox = uicheckbox(app.UIFigure);
            app.UseSubsequencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSubsequencesCheckBoxValueChanged, true);
            app.UseSubsequencesCheckBox.Text = 'Use Subsequences';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection_S(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end