classdef OptimizeAll < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OptimizationPanel           matlab.ui.container.Panel
        TrainingPlotsCheckBox       matlab.ui.control.CheckBox
        ThresholdDropDown           matlab.ui.control.DropDown
        ThresholdDropDownLabel      matlab.ui.control.Label
        OptimizeScoreDropDown       matlab.ui.control.DropDown
        OptimizeScoreDropDownLabel  matlab.ui.control.Label
        IterationsEditField         matlab.ui.control.NumericEditField
        IterationsEditFieldLabel    matlab.ui.control.Label
        RunOptimizationButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        selection       % Either 'all', 'DNN_selection', 'CML_selection' or 'S_selection'
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
        function oldPointer = startTask(app)
            oldPointer = get(app.UIFigure, 'pointer');
            app.OptimizationPanel.Enable = 'off';
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function stopTask(app, oldPointer)
            app.OptimizationPanel.Enable = 'on';
            set(app.UIFigure, 'pointer', oldPointer);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, selection)
            app.callingApp = mainApp;
            app.selection = selection;
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            oldPointer = app.startTask();

            iterations = app.IterationsEditField.Value;
            score = app.OptimizeScoreDropDown.Value;
            threshold = getThresholdId(app.ThresholdDropDown.Value);
            if app.TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end

            app.callingApp.runOptimizationFor(iterations, score, threshold, app.selection, trainingPlots, oldPointer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.RunOptimizationButton = uibutton(app.OptimizationPanel, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.IterationsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 30;
            app.OptimizeScoreDropDownLabel = uilabel(app.OptimizationPanel);
            app.OptimizeScoreDropDownLabel.Text = 'Optimize Score:';
            app.OptimizeScoreDropDown = uidropdown(app.OptimizationPanel);
            app.OptimizeScoreDropDown.Items = {'F1 Score (point-wise)', 'F1 Score (event-wise)', 'F1 Score (point-adjusted)', 'F1 Score (composite)', 'F0.5 Score (point-wise)', 'F0.5 Score (event-wise)', 'F0.5 Score (point-adjusted)', 'F0.5 Score (composite)', 'Precision (point-wise)', 'Precision (event-wise)', 'Precision (point-adjusted)', 'Recall (point-wise)', 'Recall (event-wise)', 'Recall (point-adjusted)'};
            app.OptimizeScoreDropDown.Value = 'F1 Score (point-wise)';
            app.ThresholdDropDownLabel = uilabel(app.OptimizationPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.ThresholdDropDown = uidropdown(app.OptimizationPanel);
            app.ThresholdDropDown.Items = {'Best F1 Score (point-wise)', 'Best F1 Score (event-wise)', 'Best F1 Score (point-adjusted)', 'Best F1 Score (composite)', 'Top k', 'Mean + 4 * Std', '0.5', 'Dynamic'};
            app.ThresholdDropDown.Value = 'Best F1 Score (point-wise)';
            app.TrainingPlotsCheckBox = uicheckbox(app.OptimizationPanel);
            app.TrainingPlotsCheckBox.Text = 'Training Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizeAll(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end