classdef OptimizeParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptimizationPanel               matlab.ui.container.Panel
        TrainingPlotsCheckBox           matlab.ui.control.CheckBox
        ThresholdDropDown               matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        UsePreconfiguredHyperparameterRangeCheckBox  matlab.ui.control.CheckBox
        OptimizeScoreDropDown           matlab.ui.control.DropDown
        OptimizeScoreDropDownLabel      matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        HyperparameterstoOptimizePanel  matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        OptimizationVariablesListBox    matlab.ui.control.ListBox
        RunForSelectionButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        options         % The model on which to perform optimization (struct)
        optVars         % The optimization hyperparameters
        AddPopup        % Popup for adding hyperparameter
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
        function oldPointer = startTask(app)
            oldPointer = get(app.UIFigure, 'pointer');
            app.OptimizationPanel.Enable = 'off';
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function stopTask(app, oldPointer)
            app.OptimizationPanel.Enable = 'on';
            set(app.UIFigure, 'pointer', oldPointer);
        end
        
        function addHyperparameter(app, name, value, type)
            if ismember(name, app.OptimizationVariablesListBox.ItemsData)
                uialert(app.UIFigure, "Can't add the same hyperparameter twice!", "Error", Icon="error");
                return;
            end
            app.optVars.(name).value = value;
            app.optVars.(name).type = type;
            app.OptimizationVariablesListBox.Items = [app.OptimizationVariablesListBox.Items sprintf('%s    from: %d to: %d', name, value(1), value(2))];
            app.OptimizationVariablesListBox.ItemsData = [app.OptimizationVariablesListBox.ItemsData name];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, options)
            app.callingApp = mainApp;
            app.options = options;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.AddPopup = AddHyperparameterPopup(app, app.options);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.optVars = {};
            app.OptimizationVariablesListBox.Items = {};
            app.OptimizationVariablesListBox.ItemsData = [];
        end

        % Value changed function: 
        % UsePreconfiguredHyperparameterRangeCheckBox
        function UsePreconfiguredHyperparameterRangeCheckBoxValueChanged(app, event)
            if app.UsePreconfiguredHyperparameterRangeCheckBox.Value
                app.HyperparameterstoOptimizePanel.Enable = 'off';
            else
                app.HyperparameterstoOptimizePanel.Enable = 'on';
            end
        end

        % Button pushed function: RunForSelectionButton
        function RunOptimizationButtonPushed(app, event)
            if ~isfield(app.options, 'hyperparameters')
                uialert(app.UIFigure, 'No hyperparameters found for optimization!', 'Parameter Error', Icon='error');
                return;
            end
            
            if app.UsePreconfiguredHyperparameterRangeCheckBox.Value
                optVarsPre = getOptimizationVariables(app.options.model, 'tsad_platform_config_optimization.json');
                
                % check if opt variable exists in hyperparameters
                varNames = fieldnames(optVarsPre);
                for j = 1:length(varNames)
                    flag = false;
                    if isfield(app.options.hyperparameters, 'model')
                        if isfield(app.options.hyperparameters.model, varNames{j})
                            flag = true;
                        end
                    end
                    if isfield(app.options.hyperparameters, 'data')
                        if isfield(app.options.hyperparameters.data, varNames{j})
                            flag = true;
                        end
                    end
                    if isfield(app.options.hyperparameters, 'training')
                        if isfield(app.options.hyperparameters.training, varNames{j})
                            flag = true;
                        end
                    end
                    if ~flag
                        optVarsPre = rmfield(optVarsPre, varNames{j});
                    end
                end
                
                if isempty(optVarsPre)
                    uialert(app.UIFigure, 'No hyperparameters found for optimization!', 'Parameter Error', Icon='error');
                    return;
                end

                varsToOptimize = optVarsPre;
            else
                if isempty(app.optVars)
                    uialert(app.UIFigure, 'No hyperparameters selected for optimization!', 'Parameter Error', Icon='error');
                    return;
                end

                varsToOptimize = app.optVars;
            end

            oldPointer = app.startTask();

            iterations = app.IterationsEditField.Value;
            score = app.OptimizeScoreDropDown.Value;
            threshold = getThresholdId(app.ThresholdDropDown.Value);
            if app.TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end
            

            app.callingApp.runOptimization(app.options, varsToOptimize, ...
                iterations, score, threshold, trainingPlots, oldPointer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.RunForSelectionButton = uibutton(app.OptimizationPanel, 'push');
            app.RunForSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunForSelectionButton.Text = 'Run Optimization';
            app.HyperparameterstoOptimizePanel = uipanel(app.OptimizationPanel);
            app.HyperparameterstoOptimizePanel.Enable = 'off';
            app.HyperparameterstoOptimizePanel.Title = 'Hyperparameters to Optimize';
            app.OptimizationVariablesListBox = uilistbox(app.HyperparameterstoOptimizePanel);
            app.OptimizationVariablesListBox.Items = {};
            app.OptimizationVariablesListBox.Value = {};
            app.AddButton = uibutton(app.HyperparameterstoOptimizePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ClearButton = uibutton(app.HyperparameterstoOptimizePanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.IterationsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 30;
            app.OptimizeScoreDropDownLabel = uilabel(app.OptimizationPanel);
            app.OptimizeScoreDropDownLabel.Text = 'Optimize Score:';
            app.OptimizeScoreDropDown = uidropdown(app.OptimizationPanel);
            app.OptimizeScoreDropDown.Items = {'F1 Score (point-wise)', 'F1 Score (event-wise)', 'F1 Score (point-adjusted)', 'F1 Score (composite)', 'F0.5 Score (point-wise)', 'F0.5 Score (event-wise)', 'F0.5 Score (point-adjusted)', 'F0.5 Score (composite)', 'Precision (point-wise)', 'Precision (event-wise)', 'Precision (point-adjusted)', 'Recall (point-wise)', 'Recall (event-wise)', 'Recall (point-adjusted)'};
            app.OptimizeScoreDropDown.Value = 'F1 Score (point-wise)';
            app.UsePreconfiguredHyperparameterRangeCheckBox = uicheckbox(app.OptimizationPanel);
            app.UsePreconfiguredHyperparameterRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsePreconfiguredHyperparameterRangeCheckBoxValueChanged, true);
            app.UsePreconfiguredHyperparameterRangeCheckBox.Text = 'Use Preconfigured Hyperparameter Range';
            app.UsePreconfiguredHyperparameterRangeCheckBox.Value = true;
            app.ThresholdDropDownLabel = uilabel(app.OptimizationPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.ThresholdDropDown = uidropdown(app.OptimizationPanel);
            app.ThresholdDropDown.Items = {'Best F1 Score (point-wise)', 'Best F1 Score (event-wise)', 'Best F1 Score (point-adjusted)', 'Best F1 Score (composite)', 'Top k', 'Mean + 4 * Std', '0.5', 'Dynamic'};
            app.ThresholdDropDown.Value = 'Best F1 Score (point-wise)';
            app.TrainingPlotsCheckBox = uicheckbox(app.OptimizationPanel);
            app.TrainingPlotsCheckBox.Text = 'Training Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizeParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end