classdef ThresholdSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainPanel                      matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        Tree                           matlab.ui.container.CheckBoxTree
        BestF1ScorepointwiseNode       matlab.ui.container.TreeNode
        BestF1ScoreeventwiseNode       matlab.ui.container.TreeNode
        BestF1ScorepointadjustedNode   matlab.ui.container.TreeNode
        BestF1ScorecompositeNode       matlab.ui.container.TreeNode
        BestF1ScorepointwiseGaussNode  matlab.ui.container.TreeNode
        BestF1ScoreeventwiseGaussNode  matlab.ui.container.TreeNode
        BestF1ScorepointadjustedGaussNode  matlab.ui.container.TreeNode
        BestF1ScorecompositeGaussNode  matlab.ui.container.TreeNode
        TopkNode                       matlab.ui.container.TreeNode
        TopkGaussNode                  matlab.ui.container.TreeNode
        Mean4StdNode                   matlab.ui.container.TreeNode
        DynamicNode                    matlab.ui.container.TreeNode
        SaveButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, thresholds)
            app.callingApp = mainApp;

            nodesToBeChecked = [];

            if ismember("bestFscorePointwise", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorepointwiseNode];
            end
            if ismember("bestFscoreEventwise", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScoreeventwiseNode];
            end
            if ismember("bestFscorePointAdjusted", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorepointadjustedNode];
            end
            if ismember("bestFscoreComposite", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorecompositeNode];
            end
            if ismember("bestFscorePointwiseGauss", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorepointwiseGaussNode];
            end
            if ismember("bestFscoreEventwiseGauss", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScoreeventwiseGaussNode];
            end
            if ismember("bestFscorePointAdjustedGauss", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorepointadjustedGaussNode];
            end
            if ismember("bestFscoreCompositeGauss", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorecompositeGaussNode];
            end
            if ismember("topK", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.TopkNode];
            end
            if ismember("topKGauss", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.TopkGaussNode];
            end
            if ismember("meanStd", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.Mean4StdNode];
            end
            if ismember("dynamic", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.DynamicNode];
            end

            app.Tree.CheckedNodes = nodesToBeChecked;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            thresholds = [];
            
            checkedNodes = app.Tree.CheckedNodes;
            
            if ismember(app.BestF1ScorepointwiseNode, checkedNodes)
                thresholds = [thresholds, "bestFscorePointwise"];
            end
            if ismember(app.BestF1ScoreeventwiseNode, checkedNodes)
                thresholds = [thresholds, "bestFscoreEventwise"];
            end
            if ismember(app.BestF1ScorepointadjustedNode, checkedNodes)
                thresholds = [thresholds, "bestFscorePointAdjusted"];
            end
            if ismember(app.BestF1ScorecompositeNode, checkedNodes)
                thresholds = [thresholds, "bestFscoreComposite"];
            end
            if ismember(app.BestF1ScorepointwiseGaussNode, checkedNodes)
                thresholds = [thresholds, "bestFscorePointwiseGauss"];
            end
            if ismember(app.BestF1ScoreeventwiseGaussNode, checkedNodes)
                thresholds = [thresholds, "bestFscoreEventwiseGauss"];
            end
            if ismember(app.BestF1ScorepointadjustedGaussNode, checkedNodes)
                thresholds = [thresholds, "bestFscorePointAdjustedGauss"];
            end
            if ismember(app.BestF1ScorecompositeGaussNode, checkedNodes)
                thresholds = [thresholds, "bestFscoreCompositeGauss"];
            end
            if ismember(app.TopkNode, checkedNodes)
                thresholds = [thresholds, "topK"];
            end
            if ismember(app.TopkGaussNode, checkedNodes)
                thresholds = [thresholds, "topKGauss"];
            end
            if ismember(app.Mean4StdNode, checkedNodes)
                thresholds = [thresholds, "meanStd"];
            end
            if ismember(app.DynamicNode, checkedNodes)
                thresholds = [thresholds, "dynamic"];
            end

            app.callingApp.updateThresholds(thresholds);
            app.Lamp.Color = 'green';
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.Lamp.Color = '#cccccc';
            nodes = app.Tree.CheckedNodes;
            if numel(nodes) == 0
                app.SaveButton.Enable = 'off';
            else
                app.SaveButton.Enable ='on';
            end
            
            if numel(nodes) == 1
                if nodes(1) == app.DynamicNode
                    app.SaveButton.Enable = 'off';
                else
                    app.SaveButton.Enable = 'on';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainPanel = uipanel(app.UIFigure);
            app.SaveButton = uibutton(app.MainPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Tree = uitree(app.MainPanel, 'checkbox');
            app.BestF1ScorepointwiseNode = uitreenode(app.Tree);
            app.BestF1ScorepointwiseNode.Text = 'Best F1 Score (point-wise)';
            app.BestF1ScoreeventwiseNode = uitreenode(app.Tree);
            app.BestF1ScoreeventwiseNode.Text = 'Best F1 Score (event-wise)';
            app.BestF1ScorepointadjustedNode = uitreenode(app.Tree);
            app.BestF1ScorepointadjustedNode.Text = 'Best F1 Score (point-adjusted)';
            app.BestF1ScorecompositeNode = uitreenode(app.Tree);
            app.BestF1ScorecompositeNode.Text = 'Best F1 Score (composite)';
            app.BestF1ScorepointwiseGaussNode = uitreenode(app.Tree);
            app.BestF1ScorepointwiseGaussNode.Text = 'Best F1 Score (point-wise, Gauss)';
            app.BestF1ScoreeventwiseGaussNode = uitreenode(app.Tree);
            app.BestF1ScoreeventwiseGaussNode.Text = 'Best F1 Score (event-wise, Gauss)';
            app.BestF1ScorepointadjustedGaussNode = uitreenode(app.Tree);
            app.BestF1ScorepointadjustedGaussNode.Text = 'Best F1 Score (point-adjusted, Gauss)';
            app.BestF1ScorecompositeGaussNode = uitreenode(app.Tree);
            app.BestF1ScorecompositeGaussNode.Text = 'Best F1 Score (composite, Gauss)';
            app.TopkNode = uitreenode(app.Tree);
            app.TopkNode.Text = 'Top k';
            app.TopkGaussNode = uitreenode(app.Tree);
            app.TopkGaussNode.Text = 'Top k (Gauss)';
            app.Mean4StdNode = uitreenode(app.Tree);
            app.Mean4StdNode.Text = 'Mean + 4 * Std';
            app.DynamicNode = uitreenode(app.Tree);
            app.DynamicNode.Text = 'Dynamic';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Lamp = uilamp(app.MainPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end