classdef plotData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UAVDataUIFigure  matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function legendBDCB(app,~,evnt) % toggle line on/off on legend click     
        % Get all of the lines
        lines = app.UIAxes.Children;
        
        % Next, create segments based off of the number of lines. Based on
        % where the user clicked in the legend, we will assign that click
        % location to a line. This call generates a grid of equally spaced
        % ranges, with the number of ranges equaling the number of lines
        click_locations = linspace(0,1,numel(lines)+1);
        
        % Get corresponding bin based on the 2nd value of
        % evnt.IntersectionPoint. This corresponds to the vertical distance
        % within the legend, which we can use to identify what line
        % (roughly) in the legend was clicked
        line_num = discretize(evnt.IntersectionPoint(2),click_locations);
        
        % Set the visiblility of the corresponding line 'off' or 'on'
        relevant_line = lines(line_num);
        if strcmp(relevant_line.Visible,'on')
            relevant_line.Visible = 'off';
        else
            relevant_line.Visible = 'on';
        end
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, data, titre, appTitle)
            app.UAVDataUIFigure.Visible = 'off';
            title(app.UIAxes, titre)
            app.UAVDataUIFigure.Name = appTitle;
            hold (app.UIAxes, 'on')
            cellfun(@(x) plot(app.UIAxes, x), data);
            hold (app.UIAxes, 'off')
            set(app.UIAxes.Children(1:end-1), 'Visible', 'off')
            l = legend(app.UIAxes, "Location","bestoutside"); 
%             l.Title.String = "Simulation data";            
            l.ButtonDownFcn = @app.legendBDCB;
            movegui(app.UAVDataUIFigure, "center")
            app.UAVDataUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UAVDataUIFigure = uifigure('Visible', 'off');
            app.UAVDataUIFigure.Name = 'UAV Data';
            app.UIAxes = uiaxes(app.UAVDataUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time/s')
            % Show the figure after all components are created
            app.UAVDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UAVDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UAVDataUIFigure)
        end
    end
end