classdef plotResultsAndEval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ParametricDropDown         matlab.ui.control.DropDown
        ParametricDropDownLabel    matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        NonparametricstaticPanel   matlab.ui.container.Panel
        UITable_3                  matlab.ui.control.Table
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes2_3                  matlab.ui.control.UIAxes
        UITable_2                  matlab.ui.control.Table
        zrangeSlider               matlab.ui.control.Slider
        zrangeSliderLabel          matlab.ui.control.Label
        WindowSizeSlider           matlab.ui.control.Slider
        WindowSizeSliderLabel      matlab.ui.control.Label
        AnomalyPaddingSlider       matlab.ui.control.Slider
        AnomalyPaddingSliderLabel  matlab.ui.control.Label
        UIAxes2_2                  matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        padding % Description
        res
        predi
        dataYY
        labels
        windowsz
        z_range; % Description
        pd % Description
        min_percent % Description
    end
    
    methods (Access = private)
        
        function index = getIndexes(app, data)
            try
                shift = [0; data(1:end-1)];
            catch
                shift = [0 data(1:end-1)];
            end
            change = shift ~= data;
            begin = find(data & change);
            endI = find(change & shift) -1;
            if length(endI) == length(begin) -1
                try
                    endI = [endI length(data)];
                catch
                    endI = [endI; length(data)];
                end
            end
            [0 1 1 0];
            [0 0 1 1];
            if size(begin, 1) ~= 1
                begin = begin';
                endI = endI';
            end
            index = [begin; endI; endI; begin];
        end
        
        
        function anomaly = combineAnomsAndStatic(app, res, anoms)
            d = diff(res);
            anoms(find(d == 0)) = true;
            anomaly = anoms;
        end
        
        function updateGraphs(app)
            app.windowsz = ceil(app.WindowSizeSlider.Value);
            app.padding = ceil(app.AnomalyPaddingSlider.Value);
            app.res = abs(app.predi-app.dataYY);
            app.z_range = 1:round(app.zrangeSlider.Value);
            app.zrangeSlider.Value = max(app.z_range);
            app.AnomalyPaddingSlider.Value = app.padding;
            app.WindowSizeSlider.Value = app.windowsz;
            [anom_times, threshold] = find_anomalies(app.res, 'anomaly_padding', app.padding, 'window_size', app.windowsz, 'min_percent', app.min_percent, 'z_range', app.z_range);
            threshY = [];
            threshX = [];
            for i = 1:length(threshold)
                threshY(i,:) = ones(1,app.windowsz)*threshold(i);
                threshX(i,:) = ((i-1)*app.windowsz+1:app.windowsz*i);
            end
            anoms = false(length(app.labels),1);
            for i = 1:size(anom_times,1)
                begIdx = anom_times(i,1);
                endIdx = anom_times(i,2);
                if endIdx > length(anoms)
                    endIdx = length(anoms);
                end
                anoms(begIdx:endIdx,1) = 1;
            end
            anoms = combineAnomsAndStatic(app, app.res, anoms);
            
            [fp, fn, tp] = overlap_seg(app.labels, anoms);
            pre = tp/(tp+fp);
            rec = tp/(tp+fn);
            f1_score = 2*pre*rec/(pre+rec);
            
            
            time = 1:length(app.res);
            indices = getIndexes(app, anoms);
            trueIndices = getIndexes(app, app.labels);
            plot(app.UIAxes, app.dataYY)
            YLims = get(app.UIAxes, 'YLim');
            limit = [];
            trueLims = [];
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            patch(app.UIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            try
                patch(app.UIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
                legend(app.UIAxes, 'data', "Detected Anomalies", "True Labels")
            catch ME
                legend(app.UIAxes, 'data', "Detected Anomalies")
            end
            
            plot(app.UIAxes2, time, app.res)
            hold(app.UIAxes2, "on")
            plot(app.UIAxes2, threshX', threshY', 'Color', 'red')
            hold(app.UIAxes2, "off")
            set(app.UIAxes2, 'XLim', [0 length(app.res)])
            scoresWeightedDynamic = getScores(app, anoms, app.labels);
            [fp, fn, tp] = overlap_seg(app.labels, anoms);
            pre = tp/(tp+fp);
            rec = tp/(tp+fn);
            f1_score = 2*pre*rec/(pre+rec);
            f05 = (1+0.5^2)*(pre*rec)/((0.5^2*pre)+rec);                
            scoresDynam = [pre; rec; f1_score; f05];
            app.UITable_2.Data = table(scoresWeightedDynamic, scoresDynam);
            drawnow
        end
        
        function scores = getScores(app, found, labels)
            try
                confmat = confusionmat(logical(labels),logical(found));
            tp = confmat(2,2);
            fp = confmat(1,2);
            fn = confmat(2,1);
            precision = tp/(tp+fp);
            recall = tp/(tp+fn);
            f1 = 2*precision*recall/(precision+recall);
            f05 = (1+0.5^2)*(precision*recall)/((0.5^2*precision)+recall);
            scores = [precision; recall; f1; f05];
            catch ME
                scores = [NaN; NaN; NaN; NaN];
            end
            
        end
        
        function anoms2 = plotStaticThreshResults(app, res,resAxes, dataAxes, staticThresh, time, trueIndices, param)            
            plot(resAxes, time, res)
            yline(resAxes, staticThresh, 'r')
            if param == 1
                anoms2 = res > staticThresh;
            else
                anoms2 = res > staticThresh;
            end            
            anoms2 = combineAnomsAndStatic(app, res, anoms2);
            indices2 = getIndexes(app, anoms2);
            limit2 = [];
            YLims = get(app.UIAxes_2, 'YLim');
            for i = 1:size(indices2, 2)
                limit2(1,i) = YLims(1);
                limit2(2, i) = YLims(1);
                limit2(3, i) = YLims(2);
                limit2(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            
            if ~isempty(indices2)
                patch(dataAxes, indices2, limit2, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
                try
                    patch(dataAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
                    %                 legend(dataAxes, 'data',"True Labels", "Detected Anomalies",  "Location","")
                    legend(dataAxes, 'data',"True Labels", "Detected Anomalies")
                catch ME
                    legend(dataAxes, 'data', "Detected anomalies")
                end
            else
                try
                    patch(dataAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
                    %                 legend(dataAxes, 'data',"True Labels", "Detected Anomalies",  "Location","")
                    legend(dataAxes, 'data',"True Labels", "Detected Anomalies")
                catch ME
                    
                end
            end
            
            set(resAxes, 'XLim', [0 length(app.res)])
            set(dataAxes, 'XLim', [0 length(app.res)])
            set(app.UIAxes, 'XLim', [0 length(app.res)])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, pred, dataY, labs, windowsize, staticThresh, phat)
            %Non parametric thresholding
            app.res = abs(pred-dataY);
            app.padding = 3;
            app.predi = pred;
            app.dataYY = dataY;
            app.labels = labs;
            app.windowsz = windowsize;
            app.AnomalyPaddingSlider.Limits = [1 50];
            app.WindowSizeSlider.Limits = [1 floor(length(dataY)/2)];
            app.WindowSizeSlider.Value = app.windowsz;
            app.AnomalyPaddingSlider.Value = app.padding;
            app.z_range = 1:app.zrangeSlider.Value;
            app.min_percent = 1;
            [anom_times, threshold] = find_anomalies(app.res, 'anomaly_padding', app.padding, 'window_size', windowsize, 'min_percent', app.min_percent, 'z_range', app.z_range);
            threshY = [];
            threshX = [];
            for i = 1:length(threshold)
                threshY(i,:) = ones(1,windowsize)*threshold(i);
                threshX(i,:) = ((i-1)*windowsize+1:windowsize*i);
            end
            anoms = false(length(labs),1);
            for i = 1:size(anom_times,1)
                begIdx = anom_times(i,1);
                endIdx = anom_times(i,2);
                if endIdx > length(anoms)
                    endIdx = length(anoms);
                end
                anoms(begIdx:endIdx,1) = 1;
            end
            anoms = combineAnomsAndStatic(app, app.res, anoms);
            time = 1:length(app.res);
            indices = getIndexes(app, anoms);
            trueIndices = getIndexes(app, labs);
            plot(app.UIAxes, dataY)
            YLims = get(app.UIAxes, 'YLim');
            limit = [];
            trueLims = [];
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            patch(app.UIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            try
                patch(app.UIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            legend(app.UIAxes, 'data', "Detected Anomalies", "True Labels")
            catch ME
                legend(app.UIAxes, 'data', "Detected Anomalies")
            end
            
            plot(app.UIAxes2, time, app.res)
            hold(app.UIAxes2, "on")
            plot(app.UIAxes2, threshX', threshY', 'Color', 'red')
            hold(app.UIAxes2, "off")
            set(app.UIAxes2, 'XLim', [0 length(app.res)])
            
            %Parametric thresholding
            plot(app.UIAxes_2, dataY)                
            try
                res2 = pdf(phat, app.res);
                app.pd = phat;
                plot(app.UIAxes_3, dataY)
                anoms2 = plotStaticThreshResults(app, app.res, app.UIAxes2_2, app.UIAxes_2, staticThresh(1), time, trueIndices, 0);
                anoms3 = plotStaticThreshResults(app, app.res, app.UIAxes2_3, app.UIAxes_3, staticThresh(2), time, trueIndices, 1);
                flag = true;
            catch ME
                app.ParametricDropDown.Visible = 0;
                anoms2 = plotStaticThreshResults(app, app.res, app.UIAxes2_2, app.UIAxes_2, staticThresh, time, trueIndices, 0);
                flag = false;
            end
            
            %             plot(app.UIAxes2_2, time, app.res)
            %             yline(app.UIAxes2_2, staticThresh, 'r')
            %             anoms2 = app.res > staticThresh;
            %             anoms2 = combineAnomsAndStatic(app, app.res, anoms2);
            %             indices2 = getIndexes(app, anoms2);
            %             limit2 = [];
            %             YLims = get(app.UIAxes_2, 'YLim');
            %             for i = 1:size(indices2, 2)
            %                 limit2(1,i) = YLims(1);
            %                 limit2(2, i) = YLims(1);
            %                 limit2(3, i) = YLims(2);
            %                 limit2(4, i) = YLims(2);
            %             end
            %             for i = 1:size(trueIndices, 2)
            %                 trueLims(1,i) = YLims(1);
            %                 trueLims(2, i) = YLims(1);
            %                 trueLims(3, i) = YLims(2);
            %                 trueLims(4, i) = YLims(2);
            %             end
            %             patch(app.UIAxes_2, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            %             try
            %                 patch(app.UIAxes_2, indices2, limit2, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            %                 legend(app.UIAxes_2, 'data',"True Labels", "Detected Anomalies",  "Location","")
            %             catch ME
            %                 legend(app.UIAxes_2, 'data', "True Labels", "Location","")
            %             end
            %             set(app.UIAxes2_2, 'XLim', [0 length(app.res)])
            %             set(app.UIAxes_2, 'XLim', [0 length(app.res)])
            %             set(app.UIAxes, 'XLim', [0 length(app.res)])
            
            %           Get Scores
            
            if(flag)
                scoresWeightedParam2= getScores(app, anoms3, labs);
                [fp, fn, tp] = overlap_seg(labs, anoms3);
                pre2 = tp/(tp+fp);
                rec2 = tp/(tp+fn);
                f1_score2 = 2*pre2*rec2/(pre2+rec2);
                f05_2 = (1+0.5^2)*(pre2*rec2)/((0.5^2*pre2)+rec2);
                scoresParam2 = [pre2; rec2; f1_score2; f05_2];
                app.UITable_3.Data = table(scoresWeightedParam2, scoresParam2);
                
                scoresWeightedParam= getScores(app, anoms2, labs);
                [fp, fn, tp] = overlap_seg(labs, anoms2);
                pre1 = tp/(tp+fp);
                rec1 = tp/(tp+fn);
                f1_score1 = 2*pre1*rec1/(pre1+rec1);
                f05_1 = (1+0.5^2)*(pre1*rec1)/((0.5^2*pre1)+rec1);
                scoresParam = [pre1; rec1; f1_score1; f05_1];
                app.UITable.Data = table(scoresWeightedParam, scoresParam);
            else
                assignin("base", "labs", labs)
                assignin("base", "anoms", anoms2) 
                scoresWeightedParam= getScores(app, anoms2, labs);
                [fp, fn, tp] = overlap_seg(labs, anoms2);
                pre1 = tp/(tp+fp);
                rec1 = tp/(tp+fn);
                f1_score1 = 2*pre1*rec1/(pre1+rec1);
                f05_1 = (1+0.5^2)*(pre1*rec1)/((0.5^2*pre1)+rec1);
                scoresParam = [pre1; rec1; f1_score1; f05_1];
                app.UITable.Data = table(scoresWeightedParam, scoresParam);                
                               
            end
            scoresWeightedDynamic = getScores(app, anoms, labs);
            [fp, fn, tp] = overlap_seg(labs, anoms);
            pre = tp/(tp+fp);
            rec = tp/(tp+fn);
            f1_score = 2*pre*rec/(pre+rec);
            f05 = (1+0.5^2)*(pre*rec)/((0.5^2*pre)+rec);                
            scoresDynam = [pre; rec; f1_score; f05];
            app.UITable_2.Data = table(scoresWeightedDynamic, scoresDynam);
            drawnow
        end

        % Value changing function: AnomalyPaddingSlider
        function AnomalyPaddingSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: AnomalyPaddingSlider
        function AnomalyPaddingSliderValueChanged(app, event)
            %             value = app.AnomalyPaddingSlider.Value;
            %             app.res = abs(app.predi-app.dataYY);
            %             app.padding = value;
            %             wndw = ceil(app.WindowSizeSlider.Value);
            %             app.z_range = 1:app.zrangeSlider.Value;
            %             [anom_times, threshold] = find_anomalies(app.res, 'anomaly_padding', value, 'window_size', wndw, 'min_percent', 0.2, 'z_range', app.z_range);
            %             threshY = [];
            %             threshX = [];
            %             for i = 1:length(threshold)
            %                 threshY(i,:) = ones(1,wndw)*threshold(i);
            %                 threshX(i,:) = ((i-1)*wndw+1:wndw*i);
            %             end
            %             anoms = false(length(app.labels),1);
            %             for i = 1:size(anom_times,1)
            %                 begIdx = anom_times(i,1);
            %                 endIdx = anom_times(i,2);
            %                 if endIdx > length(anoms)
            %                     endIdx = length(anoms);
            %                 end
            %                 anoms(begIdx:endIdx,1) = 1;
            %             end
            %             anoms = combineAnomsAndStatic(app, app.res, anoms);
            %             [fp, fn, tp] = overlap_seg(app.labels, anoms);
            %             pre = tp/(tp+fp);
            %             rec = tp/(tp+fn);
            %             f1_score = 2*pre*rec/(pre+rec);
            %             time = 1:length(app.res);
            %             indices = getIndexes(app, anoms);
            %             trueIndices = getIndexes(app, app.labels);
            %             plot(app.UIAxes, app.dataYY)
            %             YLims = get(app.UIAxes, 'YLim');
            %             limit = [];
            %             trueLims = [];
            %             for i = 1:size(indices, 2)
            %                 limit(1,i) = YLims(1);
            %                 limit(2, i) = YLims(1);
            %                 limit(3, i) = YLims(2);
            %                 limit(4, i) = YLims(2);
            %             end
            %             for i = 1:size(trueIndices, 2)
            %                 trueLims(1,i) = YLims(1);
            %                 trueLims(2, i) = YLims(1);
            %                 trueLims(3, i) = YLims(2);
            %                 trueLims(4, i) = YLims(2);
            %             end
            %             patch(app.UIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            %             patch(app.UIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            %             legend(app.UIAxes, 'data', "Detected Anomalies", "True Labels", "Location","")
            %             plot(app.UIAxes2, time, app.res)
            %             hold(app.UIAxes2, "on")
            %             plot(app.UIAxes2, threshX', threshY', 'Color', 'red')
            %             hold(app.UIAxes2, "off")
            %             set(app.UIAxes2, 'XLim', [0 length(app.res)])
            updateGraphs(app)
        end

        % Value changed function: WindowSizeSlider
        function WindowSizeSliderValueChanged(app, event)
            %             value = app.WindowSizeSlider.Value;
            %             value = ceil(value);
            %             app.res = abs(app.predi-app.dataYY);
            %             app.z_range = 1:app.zrangeSlider.Value;
            %             [anom_times, threshold] = find_anomalies(app.res, 'anomaly_padding', app.padding, 'window_size', value, 'min_percent', 0.2, 'z_range', app.z_range);
            %             threshY = [];
            %             threshX = [];
            %             for i = 1:length(threshold)
            %                 threshY(i,:) = ones(1,value)*threshold(i);
            %                 threshX(i,:) = ((i-1)*value+1:value*i);
            %             end
            %             anoms = false(length(app.labels),1);
            %             for i = 1:size(anom_times,1)
            %                 begIdx = anom_times(i,1);
            %                 endIdx = anom_times(i,2);
            %                 if endIdx > length(anoms)
            %                     endIdx = length(anoms);
            %                 end
            %                 anoms(begIdx:endIdx,1) = 1;
            %             end
            %             anoms = combineAnomsAndStatic(app, app.res, anoms);
            %             [fp, fn, tp] = overlap_seg(app.labels, anoms);
            %             pre = tp/(tp+fp);
            %             rec = tp/(tp+fn);
            %             f1_score = 2*pre*rec/(pre+rec);
            %             time = 1:length(app.res);
            %             indices = getIndexes(app, anoms);
            %             trueIndices = getIndexes(app, app.labels);
            %             plot(app.UIAxes, app.dataYY)
            %             YLims = get(app.UIAxes, 'YLim');
            %             limit = [];
            %             trueLims = [];
            %             for i = 1:size(indices, 2)
            %                 limit(1,i) = YLims(1);
            %                 limit(2, i) = YLims(1);
            %                 limit(3, i) = YLims(2);
            %                 limit(4, i) = YLims(2);
            %             end
            %             for i = 1:size(trueIndices, 2)
            %                 trueLims(1,i) = YLims(1);
            %                 trueLims(2, i) = YLims(1);
            %                 trueLims(3, i) = YLims(2);
            %                 trueLims(4, i) = YLims(2);
            %             end
            %             patch(app.UIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            %             patch(app.UIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            %             legend(app.UIAxes, 'data', "Detected Anomalies", "True Labels", "Location","")
            %             plot(app.UIAxes2, time, app.res)
            %             hold(app.UIAxes2, "on")
            %             plot(app.UIAxes2, threshX', threshY', 'Color', 'red')
            %             hold(app.UIAxes2, "off")
            %             set(app.UIAxes2, 'XLim', [0 length(app.res)])
            updateGraphs(app)
        end

        % Value changed function: zrangeSlider
        function zrangeSliderValueChanged(app, event)
            updateGraphs(app)
        end

        % Value changed function: ParametricDropDown
        function ParametricDropDownValueChanged(app, event)
            value = app.ParametricDropDown.Value;
            if strcmp(value, 'Yes')
                app.NonparametricstaticPanel.Visible = 1;
                app.UITable.Visible = 0;
            else
                app.NonparametricstaticPanel.Visible = 0;
                app.UITable.Visible = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data + Anomalies')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Residual')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Data + Anomalies')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Residual')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.AnomalyPaddingSliderLabel = uilabel(app.UIFigure);
            app.AnomalyPaddingSliderLabel.Text = 'Anomaly Padding';
            app.AnomalyPaddingSlider = uislider(app.UIFigure);
            app.AnomalyPaddingSlider.Limits = [0 1000];
            app.AnomalyPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @AnomalyPaddingSliderValueChanged, true);
            app.AnomalyPaddingSlider.ValueChangingFcn = createCallbackFcn(app, @AnomalyPaddingSliderValueChanging, true);
            app.WindowSizeSliderLabel = uilabel(app.UIFigure);
            app.WindowSizeSliderLabel.Text = 'Window Size';
            app.WindowSizeSlider = uislider(app.UIFigure);
            app.WindowSizeSlider.Limits = [0 1000];
            app.WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSizeSliderValueChanged, true);
            app.zrangeSliderLabel = uilabel(app.UIFigure);
            app.zrangeSliderLabel.Text = 'z-range';
            app.zrangeSlider = uislider(app.UIFigure);
            app.zrangeSlider.Limits = [1 10];
            app.zrangeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.zrangeSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.zrangeSlider.ValueChangedFcn = createCallbackFcn(app, @zrangeSliderValueChanged, true);
            app.zrangeSlider.MinorTicks = [];
            app.zrangeSlider.Value = 10;
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Weighted'; 'Unweighted'};
            app.UITable_2.RowName = {'Precision'; 'Recall'; 'F1 Score'; 'F0.5 Score'};
            app.NonparametricstaticPanel = uipanel(app.UIFigure);
            app.NonparametricstaticPanel.Title = 'Non-parametric, static';
            app.NonparametricstaticPanel.Visible = 'off';
            app.UIAxes2_3 = uiaxes(app.NonparametricstaticPanel);
            title(app.UIAxes2_3, 'Residual')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes_3 = uiaxes(app.NonparametricstaticPanel);
            title(app.UIAxes_3, 'Data + Anomalies')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UITable_3 = uitable(app.NonparametricstaticPanel);
            app.UITable_3.ColumnName = {'Weighted'; 'Unweighted'};
            app.UITable_3.RowName = {'Precision'; 'Recall'; 'F1 Score'; 'F0.5 Score'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Weighted'; 'Unweighted'};
            app.UITable.RowName = {'Precision'; 'Recall'; 'F1 Score'; 'F0.5 Score'};
            app.ParametricDropDownLabel = uilabel(app.UIFigure);
            app.ParametricDropDownLabel.Text = 'Parametric';
            app.ParametricDropDown = uidropdown(app.UIFigure);
            app.ParametricDropDown.Items = {'Yes', 'No'};
            app.ParametricDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametricDropDownValueChanged, true);
            app.ParametricDropDown.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotResultsAndEval(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end