classdef saveNetwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NetworknameEditFieldLabel  matlab.ui.control.Label
        NetworknameEditField       matlab.ui.control.EditField
        ExportnetoptionsCheckBox   matlab.ui.control.CheckBox
        SaveButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        options
        net % Description
        mainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, net, options)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, "center");
            app.InterruptDialog=uiprogressdlg(mainApp.UIFigure);
            app.net = net;
            app.options = options;
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: NetworknameEditField
        function NetworknameEditFieldValueChanged(app, event)
            app.SaveButton.Text = "Save";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin("base", app.NetworknameEditField.Value, app.net)
            if app.ExportnetoptionsCheckBox.Value == 1
                assignin("base", app.NetworknameEditField.Value+"_options", app.options)
            end
            app.SaveButton.Text = "Saved!";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close(app.InterruptDialog);
            delete(app)                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NetworknameEditFieldLabel = uilabel(app.UIFigure);
            app.NetworknameEditFieldLabel.Text = 'Network name';
            app.NetworknameEditField = uieditfield(app.UIFigure, 'text');
            app.NetworknameEditField.ValueChangedFcn = createCallbackFcn(app, @NetworknameEditFieldValueChanged, true);
            app.ExportnetoptionsCheckBox = uicheckbox(app.UIFigure);
            app.ExportnetoptionsCheckBox.Text = 'Export net options';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saveNetwork(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end