classdef simulinkModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SimulinkPanel       matlab.ui.container.Panel
        OpenModelButton     matlab.ui.control.Button
        SimulateButton      matlab.ui.control.Button
        SavenetasmatButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        network % net
        netPathAndName %saved net name and path
        maskObj
        data
        trueLabels
        threshold
        duration
        mdl
        mainApp % Description
        firstUse % Description
        method
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, method, net, data, labels, threshold)
            if strcmp(method, 'Predictive')
                app.mdl = 'PredictiveSim';
            else
                app.mdl = 'AutoEncoderPredictSim';
            end
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            app.network = net;
            app.duration = length(labels);
            app.data = timeseries(data);
            app.trueLabels = timeseries(labels');
            app.threshold = threshold;
            app.method = method;
        end

        % Button pushed function: SavenetasmatButton
        function SavenetasmatButtonPushed(app, event)
            net = app.network;
            [file, path] = uiputfile('net.mat');
            app.netPathAndName = strcat(path, file);
            save(app.netPathAndName, 'net')
            app.OpenModelButton.Enable = 1;
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            open_system(app.mdl)
            %%
            if strcmp(app.method, 'Predictive')
                app.maskObj = Simulink.Mask.get(strcat(app.mdl, "/Prediction model"));
            else
                app.maskObj = Simulink.Mask.get(strcat(app.mdl, "/Reconstruction model"));
            end
            %%
%             app.maskObj = Simulink.Mask.get(strcat(app.mdl, "/Subsystem"));
            app.SimulateButton.Enable = 1;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            inputSize = app.network.Layers(1).InputSize(1);
            %%
%             app.data = timeseries(app.mainApp.dataTest);
%             app.trueLabels = timeseries(app.mainApp.labels);
            %%
            assignin("base", "data", app.data)
            assignin("base", "labels", app.trueLabels)
            assignin("base", "threshold", app.threshold)
            app.maskObj.Parameters(1).Value = num2str(inputSize);
            app.maskObj.Parameters(2).Value = app.netPathAndName;
            sim(app.mdl, 'StopTime', num2str(app.duration))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulinkPanel = uipanel(app.UIFigure);
            app.SimulinkPanel.Title = 'Simulink';
            app.OpenModelButton = uibutton(app.SimulinkPanel, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Enable = 'off';
            app.OpenModelButton.Text = 'Open Model';
            app.SimulateButton = uibutton(app.SimulinkPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Simulate';
            app.SavenetasmatButton = uibutton(app.SimulinkPanel, 'push');
            app.SavenetasmatButton.ButtonPushedFcn = createCallbackFcn(app, @SavenetasmatButtonPushed, true);
            app.SavenetasmatButton.Text = 'Save net as .mat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulinkModel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end