classdef AutoRunConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADAutoRunConfiguratorUIFigure  matlab.ui.Figure
        BrowseButton                   matlab.ui.control.Button
        OutputPathEditField            matlab.ui.control.EditField
        OutputPathEditFieldLabel       matlab.ui.control.Label
        OptimizationPanel              matlab.ui.container.Panel
        ThresholdDropDown              matlab.ui.control.DropDown
        ThresholdLabel                 matlab.ui.control.Label
        MetricToOptimizeDropDown       matlab.ui.control.DropDown
        MetrictoOptimizeDropDownLabel  matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.NumericEditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        OpenOptimizationConfigButton   matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        StartAutoRunButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        datasetPath
        models
        options
        optimizationEnabled
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, datasetPath, models, options)
            app.callingApp = mainApp;
            app.datasetPath = datasetPath;
            app.models = models;
            app.options = options;
            
            if options.optimizationEnabled
                app.OptimizationPanel.Visible = "on";
                app.MetricToOptimizeDropDown.Items = METRIC_NAMES;
                app.ThresholdDropDown.Items = THRESHOLD_NAMES;
            else
                app.OptimizationPanel.Visible = "off";
            end
        end

        % Button pushed function: StartAutoRunButton
        function StartAutoRunButtonPushed(app, event)
            if ~exist(app.OutputPathEditField.Value, "dir")
                uialert(app.TSADAutoRunConfiguratorUIFigure, "No valid output folder selected", "Invalid Path", icon="error");
                return;
            end
            
            if app.options.optimizationEnabled
                app.options.optimizationIterations = app.IterationsEditField.Value;
                app.options.optimizationMetric = app.MetricToOptimizeDropDown.Value;
                app.options.optimizationThreshold = app.ThresholdDropDown.Value;
            end
            
            outputPath = app.OutputPathEditField.Value;

            app.callingApp.startAutoRun(app.datasetPath, outputPath, app.models, app.options);
            app.delete();
        end

        % Button pushed function: OpenOptimizationConfigButton
        function OpenOptimizationConfigButtonPushed(app, event)
            open TSADConfig_optimization.json
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.callingApp.closeProgressDialog();
            app.delete();
        end

        % Close request function: TSADAutoRunConfiguratorUIFigure
        function TSADAutoRunConfiguratorUIFigureCloseRequest(app, event)
            app.callingApp.closeProgressDialog();
            app.delete();
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            path = uigetdir(pwd);
            delete(f);

            if ~isequal(path, 0)
                app.OutputPathEditField.Value = path;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADAutoRunConfiguratorUIFigure = uifigure('Visible', 'off');
            app.TSADAutoRunConfiguratorUIFigure.Name = 'TSAD - Auto Run Configurator';
            app.TSADAutoRunConfiguratorUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.TSADAutoRunConfiguratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @TSADAutoRunConfiguratorUIFigureCloseRequest, true);
            app.StartAutoRunButton = uibutton(app.TSADAutoRunConfiguratorUIFigure, 'push');
            app.StartAutoRunButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutoRunButtonPushed, true);
            app.StartAutoRunButton.Text = 'Start Auto Run';
            app.CancelButton = uibutton(app.TSADAutoRunConfiguratorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OptimizationPanel = uipanel(app.TSADAutoRunConfiguratorUIFigure);
            app.OpenOptimizationConfigButton = uibutton(app.OptimizationPanel, 'push');
            app.OpenOptimizationConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenOptimizationConfigButtonPushed, true);
            app.OpenOptimizationConfigButton.Text = 'Open Optimization Config';
            app.IterationsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 15;
            app.MetrictoOptimizeDropDownLabel = uilabel(app.OptimizationPanel);
            app.MetrictoOptimizeDropDownLabel.Text = 'Metric to Optimize:';
            app.MetricToOptimizeDropDown = uidropdown(app.OptimizationPanel);
            app.MetricToOptimizeDropDown.Items = {};
            app.MetricToOptimizeDropDown.Value = {};
            app.ThresholdLabel = uilabel(app.OptimizationPanel);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdDropDown = uidropdown(app.OptimizationPanel);
            app.ThresholdDropDown.Items = {};
            app.ThresholdDropDown.Value = {};
            app.OutputPathEditFieldLabel = uilabel(app.TSADAutoRunConfiguratorUIFigure);
            app.OutputPathEditFieldLabel.Text = 'Output Path';
            app.OutputPathEditField = uieditfield(app.TSADAutoRunConfiguratorUIFigure, 'text');
            app.BrowseButton = uibutton(app.TSADAutoRunConfiguratorUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            % Show the figure after all components are created
            app.TSADAutoRunConfiguratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoRunConfigurator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADAutoRunConfiguratorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADAutoRunConfiguratorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADAutoRunConfiguratorUIFigure)
        end
    end
end