classdef CompTimeMetricSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADComputationTimeMetricSelectionUIFigure  matlab.ui.Figure
        MainPanel                   matlab.ui.container.Panel
        CancelButton                matlab.ui.control.Button
        Tree                        matlab.ui.container.CheckBoxTree
        F1ScorepointwiseNode        matlab.ui.container.TreeNode
        F1ScoreeventwiseNode        matlab.ui.container.TreeNode
        F1ScorepointadjustedNode    matlab.ui.container.TreeNode
        F1ScorecompositeNode        matlab.ui.container.TreeNode
        F05ScorepointwiseNode       matlab.ui.container.TreeNode
        F05ScoreeventwiseNode       matlab.ui.container.TreeNode
        F05ScorepointadjustedNode   matlab.ui.container.TreeNode
        F05ScorecompositeNode       matlab.ui.container.TreeNode
        PrecisionpointwiseNode      matlab.ui.container.TreeNode
        PrecisioneventwiseNode      matlab.ui.container.TreeNode
        PrecisionpointadjustedNode  matlab.ui.container.TreeNode
        RecallpointwiseNode         matlab.ui.container.TreeNode
        RecalleventwiseNode         matlab.ui.container.TreeNode
        RecallpointadjustedNode     matlab.ui.container.TreeNode
        AUCNode                     matlab.ui.container.TreeNode
        UpdateButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        metrics
        selectedMetrics
    end
    
    methods (Access = private)   
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, metrics)
            app.callingApp = mainApp;
            app.metrics = metrics;
            app.selectedMetrics = metrics;

            nodesToBeChecked = [];
            
            if ~isempty(metrics)
                if ismember("F1 Score (point-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F1ScorepointwiseNode];
                end
                if ismember("F1 Score (event-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F1ScoreeventwiseNode];
                end
                if ismember("F1 Score (point-adjusted)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F1ScorepointadjustedNode];
                end
                if ismember("F1 Score (composite)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F1ScorecompositeNode];
                end
                if ismember("F0.5 Score (point-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F05ScorepointwiseNode];
                end
                if ismember("F0.5 Score (event-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F05ScoreeventwiseNode];
                end
                if ismember("F0.5 Score (point-adjusted)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F05ScorepointadjustedNode];
                end
                if ismember("F0.5 Score (composite)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.F05ScorecompositeNode];
                end
                if ismember("Precision (point-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.PrecisionpointwiseNode];
                end
                if ismember("Precision (event-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.PrecisioneventwiseNode];
                end
                if ismember("Precision (point-adjusted)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.PrecisionpointadjustedNode];
                end
                if ismember("Recall (point-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.RecallpointwiseNode];
                end
                if ismember("Recall (event-wise)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.RecalleventwiseNode];
                end
                if ismember("Recall (point-adjusted)", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.RecallpointadjustedNode];
                end
                if ismember("AUC", metrics)
                    nodesToBeChecked = [nodesToBeChecked, app.AUCNode];
                end
            end

            app.Tree.CheckedNodes = nodesToBeChecked;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.callingApp.updateCompTimeMetrics(app.selectedMetrics);
            app.metrics = app.selectedMetrics;
            app.UpdateButton.Enable = 'off';
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            
            app.selectedMetrics = [];
            if ismember(app.F1ScorepointwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F1 Score (point-wise)"];
            end
            if ismember(app.F1ScoreeventwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F1 Score (event-wise)"];
            end
            if ismember(app.F1ScorepointadjustedNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F1 Score (point-adjusted)"];
            end
            if ismember(app.F1ScorecompositeNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F1 Score (composite)"];
            end
            if ismember(app.F05ScorepointwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F0.5 Score (point-wise)"];
            end
            if ismember(app.F05ScoreeventwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F0.5 Score (event-wise)"];
            end
            if ismember(app.F05ScorepointadjustedNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F0.5 Score (point-adjusted)"];
            end
            if ismember(app.F05ScorecompositeNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "F0.5 Score (composite)"];
            end
            if ismember(app.PrecisionpointwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "Precision (point-wise)"];
            end
            if ismember(app.PrecisioneventwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "Precision (event-wise)"];
            end
            if ismember(app.PrecisionpointadjustedNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "Precision (point-adjusted)"];
            end
            if ismember(app.RecallpointwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "Recall (point-wise)"];
            end
            if ismember(app.RecalleventwiseNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "Recall (event-wise)"];
            end
            if ismember(app.RecallpointadjustedNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "Recall (point-adjusted)"];
            end
            if ismember(app.AUCNode, checkedNodes)
                app.selectedMetrics = [app.selectedMetrics, "AUC"];
            end

            flag = false;
            if ~isempty(app.metrics)
                for i = 1:numel(app.selectedMetrics)
                    if ~ismember(app.selectedMetrics(i), app.metrics)
                        flag = true;
                    end
                end
                if isempty(app.selectedMetrics)
                    flag = true;
                end
            end
            
            if ~isempty(app.selectedMetrics)
                for i = 1:numel(app.metrics)
                    if ~ismember(app.metrics(i), app.selectedMetrics)
                        flag = true;
                    end
                end
                if isempty(app.metrics)
                    flag = true;
                end
            end

            if flag
                app.UpdateButton.Enable = 'on';
            else
                app.UpdateButton.Enable = 'off';
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADComputationTimeMetricSelectionUIFigure = uifigure('Visible', 'off');
            app.TSADComputationTimeMetricSelectionUIFigure.Name = 'TSAD - Computation Time Metric Selection';
            app.TSADComputationTimeMetricSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.MainPanel = uipanel(app.TSADComputationTimeMetricSelectionUIFigure);
            app.UpdateButton = uibutton(app.MainPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.Tree = uitree(app.MainPanel, 'checkbox');
            app.F1ScorepointwiseNode = uitreenode(app.Tree);
            app.F1ScorepointwiseNode.Text = 'F1 Score (point-wise)';
            app.F1ScoreeventwiseNode = uitreenode(app.Tree);
            app.F1ScoreeventwiseNode.Text = 'F1 Score (event-wise)';
            app.F1ScorepointadjustedNode = uitreenode(app.Tree);
            app.F1ScorepointadjustedNode.Text = 'F1 Score (point-adjusted)';
            app.F1ScorecompositeNode = uitreenode(app.Tree);
            app.F1ScorecompositeNode.Text = 'F1 Score (composite)';
            app.F05ScorepointwiseNode = uitreenode(app.Tree);
            app.F05ScorepointwiseNode.Text = 'F0.5 Score (point-wise)';
            app.F05ScoreeventwiseNode = uitreenode(app.Tree);
            app.F05ScoreeventwiseNode.Text = 'F0.5 Score (event-wise)';
            app.F05ScorepointadjustedNode = uitreenode(app.Tree);
            app.F05ScorepointadjustedNode.Text = 'F0.5 Score (point-adjusted)';
            app.F05ScorecompositeNode = uitreenode(app.Tree);
            app.F05ScorecompositeNode.Text = 'F0.5 Score (composite)';
            app.PrecisionpointwiseNode = uitreenode(app.Tree);
            app.PrecisionpointwiseNode.Text = 'Precision (point-wise)';
            app.PrecisioneventwiseNode = uitreenode(app.Tree);
            app.PrecisioneventwiseNode.Text = 'Precision (event-wise)';
            app.PrecisionpointadjustedNode = uitreenode(app.Tree);
            app.PrecisionpointadjustedNode.Text = 'Precision (point-adjusted)';
            app.RecallpointwiseNode = uitreenode(app.Tree);
            app.RecallpointwiseNode.Text = 'Recall (point-wise)';
            app.RecalleventwiseNode = uitreenode(app.Tree);
            app.RecalleventwiseNode.Text = 'Recall (event-wise)';
            app.RecallpointadjustedNode = uitreenode(app.Tree);
            app.RecallpointadjustedNode.Text = 'Recall (point-adjusted)';
            app.AUCNode = uitreenode(app.Tree);
            app.AUCNode.Text = 'AUC';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.CancelButton = uibutton(app.MainPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.TSADComputationTimeMetricSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompTimeMetricSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADComputationTimeMetricSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADComputationTimeMetricSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADComputationTimeMetricSelectionUIFigure)
        end
    end
end