classdef DatasetVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADDatasetVisualizationUIFigure  matlab.ui.Figure
        VisualizationPanel              matlab.ui.container.Panel
        SelectedFileTestDropDown        matlab.ui.control.DropDown
        SelectedFileLabel_2             matlab.ui.control.Label
        SelectedChannelTestDropDown     matlab.ui.control.DropDown
        SelectedChannelLabel_2          matlab.ui.control.Label
        SelectedFileTrainDropDown       matlab.ui.control.DropDown
        SelectedFileLabel               matlab.ui.control.Label
        SelectedChannelTrainDropDown    matlab.ui.control.DropDown
        SelectedChannelLabel            matlab.ui.control.Label
        RawTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        RawTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% App Status Information        
        
        info_numTrainFiles = 0;             % Number of training files
        info_numTestFiles = 0;              % Number of all testing files
        info_numChannels = 0;           % Number of channels in the dataset
        
        channelNames

        dataTrainRaw
        timestampsTrain
        labelsTrain
        filesTrain
        dataTestRaw
        allTestTimestamps
        allTestLabels
        allTestFiles
        augmentation
        intensity
        augmentated
        training


    end
    
    methods (Access = private)
        
        function plotLoadedData(app, dataset, file_idx, channel_idx)
            switch dataset
                case 'train-raw'
                    axes = app.RawTrainingDatasetPreviewUIAxes;
                    data = app.dataTrainRaw{file_idx, 1}(:, channel_idx);
                case 'test-raw'
                    axes = app.RawTestingDatasetPreviewUIAxes;
                    data = app.dataTestRaw{file_idx, 1}(:, channel_idx);
                
            end
            
            cla(axes);
            plot(axes, data);
        end


        function createPreviewLegend(app, dataset, file_idx)
            switch dataset
                case 'train-raw'
                    axes = app.RawTrainingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTrain{file_idx, 1});
                case 'test-raw'
                    axes = app.RawTestingDatasetPreviewUIAxes;
                    indices = getIndexes(app.allTestLabels{file_idx, 1});
               
            end
            
            YLims = get(axes, 'YLim');
            limits = [];
            for i = 1:size(indices, 2)
                limits(1,i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(indices) && ~isempty(limits)
                patch(axes, indices, limits, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end 

        function resetAllData(app)
            app.dataTrainRaw = [];
            app.dataTestRaw = [];
            app.timestampsTrain = [];  
            app.allTestTimestamps = [];
            app.labelsTrain = [];  
            app.allTestLabels = [];
            app.filesTrain = [];            
            app.allTestFiles = [];
        end

        function updatePreviewData(app, dataset)
            switch dataset
                case 'train-raw'
                    file = app.SelectedFileTrainDropDown.Value;
                    [~, file_idx] = ismember(file, app.SelectedFileTrainDropDown.Items);
                    channel = app.SelectedChannelTrainDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.SelectedChannelTrainDropDown.Items);
                case 'test-raw'
                    file = app.SelectedFileTestDropDown.Value;
                    [~, file_idx] = ismember(file, app.SelectedFileTestDropDown.Items);
                    channel = app.SelectedChannelTestDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.SelectedChannelTestDropDown.Items);
             
            end

            app.plotLoadedData(dataset, file_idx, channel_idx);
            app.createPreviewLegend(dataset, file_idx);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path, augmentation, intensity, training)
            
            app.augmentation=augmentation;
            app.intensity=intensity;
            app.training=training;
        
            
            cla(app.RawTrainingDatasetPreviewUIAxes);
            cla(app.RawTestingDatasetPreviewUIAxes);
            
            app.resetAllData();          
            
            try
                [app.dataTrainRaw, ...
                    app.timestampsTrain, ...
                    app.labelsTrain, ...
                    app.filesTrain, ...
                    app.dataTestRaw, ...
                    app.allTestTimestamps, ...
                    app.allTestLabels, ...
                    app.allTestFiles, ...
                    app.channelNames] = loadCustomDataset(path);
            catch
                uialert(app.TSADDatasetVisualizationUIFigure, ['No testing or testing data found! Please make make sure the selected folder contains' ...
                    'the following directories: train, test. The folders should include .csv files with fields:' ...
                    'timestamp, value1, value2, ..., is_anomaly'], ...
                            'Data Error', Icon='error');
                return;
            end

            if (app.augmentated)
                [app.dataTrainRaw, app.dataTestRaw] = augmentationData(app.dataTrainRaw, app.dataTestRaw, app.augmentation,app.intensity,app.training);
            end


            if ~isempty(app.dataTrainRaw)
                app.plotLoadedData('train-raw', 1, 1);
                app.createPreviewLegend('train-raw', 1);

                app.info_numChannels = size(app.dataTrainRaw{1, 1}, 2);

                app.SelectedFileTrainDropDown.Items = app.filesTrain;
            end

            if ~isempty(app.dataTestRaw)
                app.plotLoadedData('test-raw', 1, 1);
                app.createPreviewLegend('test-raw', 1);

                app.info_numChannels = size(app.dataTestRaw{1, 1}, 2);

                app.SelectedFileTestDropDown.Items = app.allTestFiles;
            end

            app.SelectedChannelTestDropDown.Items = app.channelNames;
            app.SelectedChannelTrainDropDown.Items = app.channelNames;
            
        end

        % Value changed function: SelectedFileTrainDropDown
        function SelectedFileTrainDropDownValueChanged(app, event)
            
            app.updatePreviewData('train-raw');
        end

        % Value changed function: SelectedFileTestDropDown
        function SelectedFileTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
            
        end

        % Value changed function: SelectedChannelTrainDropDown
        function SelectedChannelTrainDropDownValueChanged(app, event)
        
            app.updatePreviewData('train-raw');
        end

        % Value changed function: SelectedChannelTestDropDown
        function SelectedChannelTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADDatasetVisualizationUIFigure = uifigure('Visible', 'off');
            app.TSADDatasetVisualizationUIFigure.Name = 'TSAD - Dataset Visualization';
            app.TSADDatasetVisualizationUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.VisualizationPanel = uipanel(app.TSADDatasetVisualizationUIFigure);
            app.RawTestingDatasetPreviewUIAxes = uiaxes(app.VisualizationPanel);
            title(app.RawTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (raw)')
            xlabel(app.RawTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.RawTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.RawTestingDatasetPreviewUIAxes, 'Z')
            app.RawTrainingDatasetPreviewUIAxes = uiaxes(app.VisualizationPanel);
            title(app.RawTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (raw)')
            xlabel(app.RawTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.RawTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.RawTrainingDatasetPreviewUIAxes, 'Z')
            app.SelectedChannelLabel = uilabel(app.VisualizationPanel);
            app.SelectedChannelLabel.Text = 'Selected Channel:';
            app.SelectedChannelTrainDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedChannelTrainDropDown.Items = {};
            app.SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelTrainDropDownValueChanged, true);
            app.SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel = uilabel(app.VisualizationPanel);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.SelectedFileTrainDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedFileTrainDropDown.Items = {};
            app.SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedFileTrainDropDownValueChanged, true);
            app.SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_2 = uilabel(app.VisualizationPanel);
            app.SelectedChannelLabel_2.Text = 'Selected Channel:';
            app.SelectedChannelTestDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedChannelTestDropDown.Items = {};
            app.SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelTestDropDownValueChanged, true);
            app.SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_2 = uilabel(app.VisualizationPanel);
            app.SelectedFileLabel_2.Text = 'Selected File:';
            app.SelectedFileTestDropDown = uidropdown(app.VisualizationPanel);
            app.SelectedFileTestDropDown.Items = {};
            app.SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedFileTestDropDownValueChanged, true);
            app.SelectedFileTestDropDown.Value = {};
            % Show the figure after all components are created
            app.TSADDatasetVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetVisualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TSADDatasetVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADDatasetVisualizationUIFigure)
        end
    end
end