classdef ModelParametersViewAndUpdate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADModelParametersViewAndUpdateUIFigure  matlab.ui.Figure
        CloseButton   matlab.ui.control.Button
        UpdateButton  matlab.ui.control.Button
        NNUIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % The app that called this app
        callingApp

        % Model id
        modelID

        % Struct contatining config for model
        parametersConfig = struct();

        % Struct containing current values of parameters
        currentParameters = struct();

        % Neural Network layers (can be empty)
        NNLayers

        % Custom components which are created by code
        customComponentsStatic = struct();
        customComponentsConfigurable = struct();
    end
    
    methods (Access = private)
        function showModelComponents(app)
            %SHOWSELECTEDMODELUI Displays all parameter edit fields for the
            %currently selected model

            componentCounter = 0;
            
            if isfield(app.parametersConfig, "static")
                staticParameters = app.parametersConfig.static;
                staticParameterNames = fieldnames(staticParameters);

                for i = 1:numel(staticParameterNames)
                    name = staticParameterNames{i};
                    value = staticParameters.(name);               
                    app.addStaticComponent(name, value, componentCounter);
                    componentCounter = componentCounter + 1;
                end
            end

            % Increment component counter to add gap between static and
            % configurable parameters
            componentCounter = componentCounter + 1;

            if isfield(app.parametersConfig, "configurable")
                configurableParameter = app.parametersConfig.configurable;
                configurableParameterNames = fieldnames(configurableParameter);

                for i = 1:numel(configurableParameterNames)
                    name = configurableParameterNames{i};
                    value = configurableParameter.(name);
                    value.initialValue = app.currentParameters.(name);
                    app.addConfigurableComponent(name, value, componentCounter);
                    componentCounter = componentCounter + 1;
                end
            end
        end

        function addStaticComponent(app, name, value, componentCounter)
            %ADDSTATICCOMPONENT Adds a static component according to its
            %type

            nameComponentPosition = [20, 658 - 30 * componentCounter, 190, 22];
            valueComponentPosition = [220, 658 - 30 * componentCounter, 360, 22];
    
            componentID = sprintf("Component_%d", componentCounter);
            
            % Create Name Label
            nameComponent = uilabel(app.TSADModelParametersViewAndUpdateUIFigure);
            nameComponent.Position = nameComponentPosition;
            nameComponent.Text = name;

            % Create Non-editable EditField
            valueComponent = uieditfield(app.TSADModelParametersViewAndUpdateUIFigure);
            valueComponent.Position = valueComponentPosition;
            valueComponent.Value = value;
            valueComponent.Editable = false;
            valueComponent.BackgroundColor = [0.95 0.95 0.95];

            app.customComponentsStatic.(componentID).nameComponent = nameComponent;
            app.customComponentsStatic.(componentID).valueComponent = valueComponent;
        end

        function addConfigurableComponent(app, name, value, componentCounter)
            %ADDCONFIGURABLECOMPONENT Adds a configurable component
            %according to its type

            nameComponentPosition = [20, 658 - 30 * componentCounter, 190, 22];
            valueComponentPosition = [220, 658 - 30 * componentCounter, 360, 22];
    
            componentID = sprintf("Component_%d", componentCounter);
            
    
            switch value.type
                case "categorical"
                    % Create Label
                    nameComponent = uilabel(app.TSADModelParametersViewAndUpdateUIFigure);
                    nameComponent.Position = nameComponentPosition;
                    nameComponent.Text = name;
    
                    % Create DropDown
                    valueComponent = uidropdown(app.TSADModelParametersViewAndUpdateUIFigure);
                    valueComponent.Items = string(value.value);
                    valueComponent.ItemsData = value.value;
                    valueComponent.Position = valueComponentPosition;
                    valueComponent.Value = value.initialValue;
                case "integer"
                    % Create Label
                    nameComponent = uilabel(app.TSADModelParametersViewAndUpdateUIFigure);
                    nameComponent.Position = nameComponentPosition;
                    nameComponent.Text = name;
    
                    % Create EditField
                    valueComponent = uieditfield(app.TSADModelParametersViewAndUpdateUIFigure, "numeric");
                    valueComponent.Limits = value.value;
                    valueComponent.Position = valueComponentPosition;
                    valueComponent.Value = value.initialValue;
                    valueComponent.RoundFractionalValues = true;
                    valueComponent.HorizontalAlignment = "left";
                case "real"
                    % Create Label
                    nameComponent = uilabel(app.TSADModelParametersViewAndUpdateUIFigure);
                    nameComponent.Position = nameComponentPosition;
                    nameComponent.Text = name;
    
                    % Create EditField
                    valueComponent = uieditfield(app.TSADModelParametersViewAndUpdateUIFigure, "numeric");
                    valueComponent.Limits = value.value;
                    valueComponent.Position = valueComponentPosition;
                    valueComponent.Value = value.initialValue;
                    valueComponent.RoundFractionalValues = false;
                    valueComponent.HorizontalAlignment = "left";
            end

            app.customComponentsConfigurable.(componentID).nameComponent = nameComponent;
            app.customComponentsConfigurable.(componentID).valueComponent = valueComponent;
        end

        function showNNLayers(app)
            if ~isempty(app.NNLayers)
                conn = app.NNLayers.Connections;
                s = extractBefore(conn.Source, "/");
                d = extractBefore(conn.Destination, "/");
                for i = 1:size(conn, 1)
                    if ~isempty(s{i})
                        conn.Source{i} = s{i};
                    end
                    if ~isempty(d{i})
                        conn.Destination{i} = d{i};
                    end
                end
                EndNodes = [conn.Source conn.Destination];
                nodeTable = table(EndNodes);
                graph = digraph(nodeTable);

                plot(app.NNUIAxes, graph);
                app.NNUIAxes.Title.String = "Network Architecture";
            else
                app.NNUIAxes.Title.String = "";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, modelID, parameters, NNLayers)
            app.callingApp = mainApp;
            
            app.modelID = modelID;
            app.currentParameters = parameters;
            app.NNLayers = NNLayers;

            % Load config
            modelConfigs = readJSONToStruct("TSADConfig_models.json");
            app.parametersConfig = modelConfigs.(app.currentParameters.className).parameters;

            axis(app.NNUIAxes, "off");

            app.showModelComponents();
            app.showNNLayers();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % First load static parameters
            newParameters = app.parametersConfig.static;       
            
            % Then get all configurable parameters
            fields = fieldnames(app.customComponentsConfigurable);
            for i = 1:numel(fields)
                nameComponent = app.customComponentsConfigurable.(fields{i}).nameComponent.Text;
                newParameters.(nameComponent) = app.customComponentsConfigurable.(fields{i}).valueComponent.Value;
            end  

            app.callingApp.updateModel(app.modelID, newParameters);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADModelParametersViewAndUpdateUIFigure = uifigure('Visible', 'off');
            app.TSADModelParametersViewAndUpdateUIFigure.Name = 'TSAD - Model Parameters View And Update';
            app.TSADModelParametersViewAndUpdateUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.NNUIAxes = uiaxes(app.TSADModelParametersViewAndUpdateUIFigure);
            xlabel(app.NNUIAxes, 'X')
            ylabel(app.NNUIAxes, 'Y')
            zlabel(app.NNUIAxes, 'Z')
            app.NNUIAxes.XTick = [];
            app.NNUIAxes.YTick = [];
            app.UpdateButton = uibutton(app.TSADModelParametersViewAndUpdateUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CloseButton = uibutton(app.TSADModelParametersViewAndUpdateUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.TSADModelParametersViewAndUpdateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelParametersViewAndUpdate(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADModelParametersViewAndUpdateUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADModelParametersViewAndUpdateUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADModelParametersViewAndUpdateUIFigure)
        end
    end
end