classdef ModelSelection_CML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AddModelPanel              matlab.ui.container.Panel
        DetectionModeButtonGroup   matlab.ui.container.ButtonGroup
        NoveltyDetectionButton     matlab.ui.control.RadioButton
        OutlierDetectionButton     matlab.ui.control.RadioButton
        MerlinDetectionModeRadioButtonGroup  matlab.ui.container.ButtonGroup
        MerlinMergeAllPredictionsButton  matlab.ui.control.RadioButton
        MerlinUseMedianDiscordLocationsButton  matlab.ui.control.RadioButton
        IsMultivariateCheckBox     matlab.ui.control.CheckBox
        ModelSelectionDropDown     matlab.ui.control.DropDown
        ModelLabel                 matlab.ui.control.Label
        AddToModelSelectionButton  matlab.ui.control.Button
        ModelHyperparametersPanel  matlab.ui.container.Panel
        ModelParameterEditField_1  matlab.ui.control.NumericEditField
        ModelParameterDropDown_1   matlab.ui.control.DropDown
        ModelParameterLabel_4      matlab.ui.control.Label
        ModelParameterEditField_4  matlab.ui.control.NumericEditField
        ModelParameterLabel_3      matlab.ui.control.Label
        ModelParameterEditField_3  matlab.ui.control.NumericEditField
        ModelParameterLabel_2      matlab.ui.control.Label
        ModelParameterLabel_1      matlab.ui.control.Label
        ModelParameterEditField_2  matlab.ui.control.NumericEditField
        DataAndTrainingHyperparametersPanel  matlab.ui.container.Panel
        StepSizeLabel              matlab.ui.control.Label
        WindowSizeLabel            matlab.ui.control.Label
        UseSubsequencesCheckBox    matlab.ui.control.CheckBox
        StepSizeEditField          matlab.ui.control.NumericEditField
        WindowSizeEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        callingApp % The app that called this app
    end
    
    methods (Access = private)
        
        function showHyperparametersFor(app, model)
            % Displays the necessary edit fields for the selected model
            switch model
                case 'iForest'
                    app.UseSubsequencesCheckBox.Visible = 'on';
                    if app.UseSubsequencesCheckBox.Value
                        app.WindowSizeEditField.Visible = 'on';
                        app.WindowSizeLabel.Visible = 'on';
                        if app.DetectionModeButtonGroup.SelectedObject ~= app.OutlierDetectionButton
                            app.StepSizeEditField.Visible = 'on';
                            app.StepSizeLabel.Visible = 'on';
                        end
                    end
                    app.ModelParameterEditField_1.Value = 10;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'numLearners';
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.ModelParameterEditField_2.Value = 128;
                    app.ModelParameterEditField_2.Visible = 'on';
                    app.ModelParameterLabel_2.Text = 'observationsPerLearner';
                    app.ModelParameterLabel_2.Visible = 'on';
                    app.IsMultivariateCheckBox.Visible = 'on';
                    app.DetectionModeButtonGroup.Visible = 'on';
                case 'OC-SVM'
                    app.UseSubsequencesCheckBox.Visible = 'on';
                    if app.UseSubsequencesCheckBox.Value
                        app.WindowSizeEditField.Visible = 'on';
                        app.WindowSizeLabel.Visible = 'on';
                        if app.DetectionModeButtonGroup.SelectedObject ~= app.OutlierDetectionButton
                            app.StepSizeEditField.Visible = 'on';
                            app.StepSizeLabel.Visible = 'on';
                        end
                    end
                    app.IsMultivariateCheckBox.Visible = 'on';
                    app.DetectionModeButtonGroup.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'kernelFunction';
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.ModelParameterDropDown_1.Items = ["gaussian", "rbf", "linear", "polynomial"];
                    app.ModelParameterDropDown_1.Visible = 'on';
                case 'LOF'
                    app.ModelParameterEditField_1.Value = 10;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'k';
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.UseSubsequencesCheckBox.Visible = 'on';
                    if app.UseSubsequencesCheckBox.Value
                        app.WindowSizeEditField.Visible = 'on';
                        app.WindowSizeLabel.Visible = 'on';
                    end
                    app.IsMultivariateCheckBox.Visible = 'on';
                case 'LDOF'
                    app.ModelParameterEditField_1.Value = 10;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'k';
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.UseSubsequencesCheckBox.Visible = 'on';
                    if app.UseSubsequencesCheckBox.Value
                        app.WindowSizeEditField.Visible = 'on';
                        app.WindowSizeLabel.Visible = 'on';
                    end
                    app.IsMultivariateCheckBox.Visible = 'on';
                case 'ABOD'
                    app.UseSubsequencesCheckBox.Visible = 'on';
                    if app.UseSubsequencesCheckBox.Value
                        app.WindowSizeEditField.Visible = 'on';
                        app.WindowSizeLabel.Visible = 'on';
                    end
                    app.IsMultivariateCheckBox.Visible = 'on';
                case 'Merlin'
                    app.ModelParameterEditField_1.Value = 20;
                    app.ModelParameterEditField_1.Visible = 'on';
                    app.ModelParameterLabel_1.Text = 'minL';
                    app.ModelParameterLabel_1.Visible = 'on';
                    app.ModelParameterEditField_2.Value = 100;
                    app.ModelParameterEditField_2.Visible = 'on';
                    app.ModelParameterLabel_2.Text = 'maxL';
                    app.ModelParameterLabel_2.Visible = 'on';
                    app.MerlinDetectionModeRadioButtonGroup.Visible = 'on';
            end
        end
        
        function resetHyperparameters(app)
            app.ModelParameterEditField_1.Visible = 'off';
            app.ModelParameterEditField_2.Visible = 'off';
            app.ModelParameterEditField_3.Visible = 'off';
            app.ModelParameterEditField_4.Visible = 'off';
            app.ModelParameterDropDown_1.Visible = 'off';
            app.ModelParameterDropDown_1.Items = {};
            app.ModelParameterDropDown_1.ItemsData = [];
            app.ModelParameterLabel_1.Text = '';
            app.ModelParameterLabel_2.Text = '';
            app.ModelParameterLabel_3.Text = '';
            app.ModelParameterLabel_4.Text = '';
            app.ModelParameterLabel_1.Visible = 'off';
            app.ModelParameterLabel_2.Visible = 'off';
            app.ModelParameterLabel_3.Visible = 'off';
            app.ModelParameterLabel_4.Visible = 'off';
            app.UseSubsequencesCheckBox.Visible = 'off';
            app.WindowSizeEditField.Visible = 'off';
            app.WindowSizeLabel.Visible = 'off';
            app.StepSizeEditField.Visible = 'off';
            app.StepSizeLabel.Visible = 'off';
            app.IsMultivariateCheckBox.Visible = 'off';
            app.DetectionModeButtonGroup.Visible = 'off';
            app.MerlinDetectionModeRadioButtonGroup.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: AddToModelSelectionButton
        function AddToModelSelectionButtonPushed(app, event)
            % Creates the modelOptions struct with all necessary modelOptions to
            % identify the configured model
            
            modelOptions.type = 'CML';
            modelOptions.name = app.ModelSelectionDropDown.Value;

            switch app.ModelSelectionDropDown.Value
                case 'iForest'
                    modelOptions.dataType = 1;
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    if app.DetectionModeButtonGroup.SelectedObject == app.OutlierDetectionButton
                        modelOptions.requiresPriorTraining = false;
                    else
                        modelOptions.requiresPriorTraining = true;
                        modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    end
                    modelOptions.outputsLabels = false;

                    modelOptions.hyperparameters.numLearners.value = app.ModelParameterEditField_1.Value;
                    modelOptions.hyperparameters.numLearners.type = 'integer';
                    modelOptions.hyperparameters.numObservationsPerLearner.value = app.ModelParameterEditField_2.Value;
                    modelOptions.hyperparameters.numObservationsPerLearner.type = 'integer';
                    if app.UseSubsequencesCheckBox.Value
                        modelOptions.useSubsequences = true;
                        modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                        modelOptions.hyperparameters.windowSize.type = 'integer';
                        if app.DetectionModeButtonGroup.SelectedObject == app.NoveltyDetectionButton 
                            modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                            modelOptions.hyperparameters.stepSize.type = 'integer';
                        end
                    else
                        modelOptions.useSubsequences = false;
                    end
                case 'OC-SVM'
                    modelOptions.dataType = 1;
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    if app.DetectionModeButtonGroup.SelectedObject == app.OutlierDetectionButton
                        modelOptions.requiresPriorTraining = false;
                    else
                        modelOptions.requiresPriorTraining = true;
                        modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    end
                    modelOptions.outputsLabels = true;
                    
                    modelOptions.hyperparameters.kernelFunction.value = string(app.ModelParameterDropDown_1.Value);
                    modelOptions.hyperparameters.kernelFunction.type = 'categorical';
                    if app.UseSubsequencesCheckBox.Value
                        modelOptions.useSubsequences = true;
                        modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                        modelOptions.hyperparameters.windowSize.type = 'integer';
                        if app.DetectionModeButtonGroup.SelectedObject == app.NoveltyDetectionButton 
                            modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                            modelOptions.hyperparameters.stepSize.type = 'integer';
                        end
                    else
                        modelOptions.useSubsequences = false;
                    end
                case 'ABOD'
                    modelOptions.dataType = 1;
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = false;
                    modelOptions.outputsLabels = false;

                    if app.UseSubsequencesCheckBox.Value
                        modelOptions.useSubsequences = true;
                        modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                        modelOptions.hyperparameters.windowSize.type = 'integer';
                    else
                        modelOptions.useSubsequences = false;
                    end
                case 'LOF'
                    modelOptions.dataType = 1;
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = false;
                    modelOptions.outputsLabels = false;

                    modelOptions.hyperparameters.k.value = app.ModelParameterEditField_1.Value;
                    modelOptions.hyperparameters.k.type = 'integer';
                    if app.UseSubsequencesCheckBox.Value
                        modelOptions.useSubsequences = true;
                        modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                        modelOptions.hyperparameters.windowSize.type = 'integer';
                    else
                        modelOptions.useSubsequences = false;
                    end
                case 'LDOF'
                    modelOptions.dataType = 1;
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = false;
                    modelOptions.outputsLabels = false;

                    modelOptions.hyperparameters.k.value = app.ModelParameterEditField_1.Value;
                    modelOptions.hyperparameters.k.type = 'integer';
                    if app.UseSubsequencesCheckBox.Value
                        modelOptions.useSubsequences = true;
                        modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                        modelOptions.hyperparameters.windowSize.type = 'integer';
                    else
                        modelOptions.useSubsequences = false;
                    end
                case 'Merlin'
                    modelOptions.isMultivariate = false;
                    modelOptions.requiresPriorTraining = false;
                    modelOptions.outputsLabels = true;
                    modelOptions.useSubsequences = false;

                    modelOptions.hyperparameters.minL.value = app.ModelParameterEditField_1.Value;
                    modelOptions.hyperparameters.minL.type = 'integer';
                    modelOptions.hyperparameters.maxL.value = app.ModelParameterEditField_2.Value;
                    modelOptions.hyperparameters.maxL.type = 'integer';
                    
                    if app.MerlinDetectionModeRadioButtonGroup.SelectedObject == app.MerlinUseMedianDiscordLocationsButton
                        modelOptions.hyperparameters.mode.value = "median";
                        modelOptions.hyperparameters.mode.type = 'categorical';
                    else
                        modelOptions.hyperparameters.mode.value = "merged";
                        modelOptions.hyperparameters.mode.type = 'categorical';
                    end
            end
            
            app.callingApp.addModelToList(modelOptions);
        end

        % Value changed function: ModelSelectionDropDown
        function ModelSelectionDropDownValueChanged(app, event)
            value = app.ModelSelectionDropDown.Value;

            app.resetHyperparameters();
            app.showHyperparametersFor(value);
        end

        % Value changed function: UseSubsequencesCheckBox
        function UseSubsequencesCheckBoxValueChanged(app, event)
           if app.UseSubsequencesCheckBox.Value
               app.WindowSizeEditField.Visible = 'on';
               app.WindowSizeLabel.Visible = 'on';

               if strcmp(app.ModelSelectionDropDown.Value, 'iForest') || strcmp(app.ModelSelectionDropDown.Value,'OC-SVM')
                   if app.DetectionModeButtonGroup.SelectedObject ~= app.OutlierDetectionButton
                       app.StepSizeEditField.Visible = 'on';
                       app.StepSizeLabel.Visible = 'on';
                   end
               else
                   app.StepSizeEditField.Visible = 'on';
                   app.StepSizeLabel.Visible = 'on';
               end
           else
               app.WindowSizeEditField.Visible = 'off';
               app.WindowSizeLabel.Visible = 'off';
               app.StepSizeEditField.Visible = 'off';
               app.StepSizeLabel.Visible = 'off';
           end            
        end

        % Selection changed function: DetectionModeButtonGroup
        function DetectionModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DetectionModeButtonGroup.SelectedObject;
            if selectedButton == app.OutlierDetectionButton
                app.StepSizeEditField.Visible = 'off';
                app.StepSizeLabel.Visible = 'off';
            else
                if app.UseSubsequencesCheckBox.Value
                    app.StepSizeEditField.Visible = 'on';
                    app.StepSizeLabel.Visible = 'on';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddModelPanel = uipanel(app.UIFigure);
            app.DataAndTrainingHyperparametersPanel = uipanel(app.AddModelPanel);
            app.DataAndTrainingHyperparametersPanel.Title = 'Data and Training Hyperparameters';
            app.WindowSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.Visible = 'off';
            app.WindowSizeEditField.Value = 100;
            app.StepSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.Visible = 'off';
            app.StepSizeEditField.Value = 1;
            app.UseSubsequencesCheckBox = uicheckbox(app.DataAndTrainingHyperparametersPanel);
            app.UseSubsequencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSubsequencesCheckBoxValueChanged, true);
            app.UseSubsequencesCheckBox.Text = 'Use Subsequences';
            app.WindowSizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.WindowSizeLabel.Visible = 'off';
            app.WindowSizeLabel.Text = 'Window Size';
            app.StepSizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.StepSizeLabel.Visible = 'off';
            app.StepSizeLabel.Text = 'Step Size';
            app.ModelHyperparametersPanel = uipanel(app.AddModelPanel);
            app.ModelHyperparametersPanel.Title = 'Model Hyperparameters';
            app.ModelParameterEditField_2 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_2.Limits = [0 Inf];
            app.ModelParameterEditField_2.Value = 128;
            app.ModelParameterLabel_1 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_1.Text = 'numLearners';
            app.ModelParameterLabel_2 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_2.Text = 'observationsPerLearner';
            app.ModelParameterEditField_3 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_3.Limits = [0 Inf];
            app.ModelParameterEditField_3.Visible = 'off';
            app.ModelParameterLabel_3 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_3.Visible = 'off';
            app.ModelParameterLabel_3.Text = '';
            app.ModelParameterEditField_4 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_4.Limits = [0 Inf];
            app.ModelParameterEditField_4.Visible = 'off';
            app.ModelParameterLabel_4 = uilabel(app.ModelHyperparametersPanel);
            app.ModelParameterLabel_4.Visible = 'off';
            app.ModelParameterLabel_4.Text = '';
            app.ModelParameterDropDown_1 = uidropdown(app.ModelHyperparametersPanel);
            app.ModelParameterEditField_1 = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelParameterEditField_1.Limits = [0 Inf];
            app.ModelParameterEditField_1.Value = 10;
            app.AddToModelSelectionButton = uibutton(app.AddModelPanel, 'push');
            app.AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddToModelSelectionButtonPushed, true);
            app.AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.ModelLabel = uilabel(app.AddModelPanel);
            app.ModelLabel.Text = 'Model';
            app.ModelSelectionDropDown = uidropdown(app.AddModelPanel);
            app.ModelSelectionDropDown.Items = {'iForest', 'OC-SVM', 'LOF', 'ABOD', 'LDOF', 'Merlin'};
            app.ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSelectionDropDownValueChanged, true);
            app.ModelSelectionDropDown.Value = 'iForest';
            app.IsMultivariateCheckBox = uicheckbox(app.AddModelPanel);
            app.IsMultivariateCheckBox.Tooltip = {'If unchecked, a separate model will be trained for each channel of the dataset (If the dataset is multivariate, otherwise this button doesn''t do anything)'};
            app.IsMultivariateCheckBox.Text = 'Is Multivariate';
            app.IsMultivariateCheckBox.Value = true;
            app.MerlinDetectionModeRadioButtonGroup = uibuttongroup(app.AddModelPanel);
            app.MerlinDetectionModeRadioButtonGroup.Visible = 'off';
            app.MerlinUseMedianDiscordLocationsButton = uiradiobutton(app.MerlinDetectionModeRadioButtonGroup);
            app.MerlinUseMedianDiscordLocationsButton.Tooltip = {'Calculate the median discord location of each discords across all subsequence lengths'};
            app.MerlinUseMedianDiscordLocationsButton.Text = 'Use median discord locations';
            app.MerlinUseMedianDiscordLocationsButton.Value = true;
            app.MerlinMergeAllPredictionsButton = uiradiobutton(app.MerlinDetectionModeRadioButtonGroup);
            app.MerlinMergeAllPredictionsButton.Tooltip = {'Calculate the detected labels for all subsequence lenghts and merge them afterwards'};
            app.MerlinMergeAllPredictionsButton.Text = 'Merge all predictions';
            app.DetectionModeButtonGroup = uibuttongroup(app.AddModelPanel);
            app.DetectionModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DetectionModeButtonGroupSelectionChanged, true);
            app.OutlierDetectionButton = uiradiobutton(app.DetectionModeButtonGroup);
            app.OutlierDetectionButton.Text = 'Outlier Detection';
            app.OutlierDetectionButton.Value = true;
            app.NoveltyDetectionButton = uiradiobutton(app.DetectionModeButtonGroup);
            app.NoveltyDetectionButton.Text = 'Novelty Detection (requires train data)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection_CML(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end