classdef ModelSelection_DNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AddModelPanel                 matlab.ui.container.Panel
        ReconstructionErrorTypeLabel  matlab.ui.control.Label
        ReconstructionErrorTypeDropDown  matlab.ui.control.DropDown
        IsMultivariateCheckBox        matlab.ui.control.CheckBox
        ScoringFunctionDropDown       matlab.ui.control.DropDown
        ScoringFunctionLabel          matlab.ui.control.Label
        ModelSelectionDropDown        matlab.ui.control.DropDown
        ModelLabel                    matlab.ui.control.Label
        AddToModelSelectionButton     matlab.ui.control.Button
        ModelHyperparametersPanel     matlab.ui.container.Panel
        ModelHyperparameter_2_Label   matlab.ui.control.Label
        ModelHyperparameter_1_Label   matlab.ui.control.Label
        ModelHyperparameter_2_EditField  matlab.ui.control.NumericEditField
        ModelHyperparameter_1_EditField  matlab.ui.control.NumericEditField
        DataAndTrainingHyperparametersPanel  matlab.ui.container.Panel
        LearningRateEditField         matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel    matlab.ui.control.Label
        RatioLabel                    matlab.ui.control.Label
        RatioEditField                matlab.ui.control.NumericEditField
        MinibatchSizeEditField        matlab.ui.control.NumericEditField
        MinibatchSizeLabel            matlab.ui.control.Label
        EpochsEditField               matlab.ui.control.NumericEditField
        EpochsLabel                   matlab.ui.control.Label
        StepSizeEditField             matlab.ui.control.NumericEditField
        StepsizeLabel                 matlab.ui.control.Label
        WindowSizeEditField           matlab.ui.control.NumericEditField
        WindowsizeLabel               matlab.ui.control.Label
        UseValDataCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callingApp % The app that called this app
    end
    
    methods (Access = private)
        
        function showModelHyperparametersFor(app, model)
            % Displays the necessary edit fields for the selected model
            switch model
                case 'FC AE'                    
                    app.ModelHyperparameter_1_EditField.Value = 32;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Neurons';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                    app.ReconstructionErrorTypeDropDown.Visible = 'on';
                    app.ReconstructionErrorTypeLabel.Visible = 'on';
                case 'LSTM (reconstruction)'
                    app.ModelHyperparameter_1_EditField.Value = 64;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                    app.ReconstructionErrorTypeDropDown.Visible = 'on';
                    app.ReconstructionErrorTypeLabel.Visible = 'on';
                case 'Hybrid CNN-LSTM (reconstruction)'
                    app.ModelHyperparameter_1_EditField.Value = 50;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                    app.ModelHyperparameter_2_EditField.Value = 32;
                    app.ModelHyperparameter_2_EditField.Visible = 'on';
                    app.ModelHyperparameter_2_Label.Visible = 'on';
                    app.ModelHyperparameter_2_Label.Text = 'Filter';
                    app.ReconstructionErrorTypeDropDown.Visible = 'on';
                    app.ReconstructionErrorTypeLabel.Visible = 'on';
                case 'TCN AE'
                    app.ModelHyperparameter_1_EditField.Value = 32;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Filter';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                    app.WindowSizeEditField.Value = 32;
                    app.ReconstructionErrorTypeDropDown.Visible = 'on';
                    app.ReconstructionErrorTypeLabel.Visible = 'on';
                case 'LSTM'
                    app.ModelHyperparameter_1_EditField.Value = 64;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                case 'Hybrid CNN-LSTM'
                    app.ModelHyperparameter_1_EditField.Value = 50;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                    app.ModelHyperparameter_2_EditField.Value = 32;
                    app.ModelHyperparameter_2_EditField.Visible = 'on';
                    app.ModelHyperparameter_2_Label.Text = 'Filter';
                    app.ModelHyperparameter_2_Label.Visible = 'on';
                case 'GRU'
                    app.ModelHyperparameter_1_EditField.Value = 64;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Hidden Units';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                case 'CNN (DeepAnT)'
                    app.ModelHyperparameter_1_EditField.Value = 8;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Filter';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                case 'ResNet'
                    app.ModelHyperparameter_1_EditField.Value = 32;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Filter';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
                case 'MLP'
                    app.ModelHyperparameter_1_EditField.Value = 32;
                    app.ModelHyperparameter_1_EditField.Visible = 'on';
                    app.ModelHyperparameter_1_Label.Text = 'Neurons';
                    app.ModelHyperparameter_1_Label.Visible = 'on';
            end
        end
        
        function resetHyperparameters(app)
            app.ModelHyperparameter_1_EditField.Visible = 'off';
            app.ModelHyperparameter_2_EditField.Visible = 'off';
            app.ModelHyperparameter_1_Label.Text = '';
            app.ModelHyperparameter_2_Label.Text = '';
            app.ModelHyperparameter_1_Label.Visible = 'off';
            app.ModelHyperparameter_2_Label.Visible = 'off';
            app.ReconstructionErrorTypeDropDown.Visible = 'off';
            app.ReconstructionErrorTypeLabel.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: AddToModelSelectionButton
        function AddToModelSelectionButtonPushed(app, event)
            % Creates the modelOptions struct with all necessary modelOptions to
            % identify the configured model
            
            modelOptions.type = 'DNN';
            modelOptions.name = app.ModelSelectionDropDown.Value;

            switch app.ModelSelectionDropDown.Value
                case 'FC AE'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'reconstructive';
                    modelOptions.dataType = 1;

                    modelOptions.hyperparameters.neurons.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.neurons.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                    modelOptions.hyperparameters.reconstructionErrorType.value = app.ReconstructionErrorTypeDropDown.Value;
                    modelOptions.hyperparameters.reconstructionErrorType.type = 'categorical';
                case 'LSTM (reconstruction)'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'reconstructive';
                    modelOptions.dataType = 2;

                    modelOptions.hyperparameters.hiddenUnits.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.hiddenUnits.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                    modelOptions.hyperparameters.reconstructionErrorType.value = app.ReconstructionErrorTypeDropDown.Value;
                    modelOptions.hyperparameters.reconstructionErrorType.type = 'categorical';
                case 'Hybrid CNN-LSTM (reconstruction)'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'reconstructive';
                    modelOptions.dataType = 2;

                    modelOptions.hyperparameters.hiddenUnits.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.hiddenUnits.type = 'integer';
                    modelOptions.hyperparameters.filter.value = app.ModelHyperparameter_2_EditField.Value;
                    modelOptions.hyperparameters.filter.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                    modelOptions.hyperparameters.reconstructionErrorType.value = app.ReconstructionErrorTypeDropDown.Value;
                    modelOptions.hyperparameters.reconstructionErrorType.type = 'categorical';
                case 'TCN AE'
                    if mod(app.WindowSizeEditField.Value, 4) ~= 0
                        uialert(app.UIFigure, "Window size must be divisible by 4 for the TCN AE.", "Parameter Error", Icon="error");
                        return;
                    end
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'reconstructive';
                    modelOptions.dataType = 2;

                    modelOptions.hyperparameters.filter.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.filter.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                    modelOptions.hyperparameters.reconstructionErrorType.value = app.ReconstructionErrorTypeDropDown.Value;
                    modelOptions.hyperparameters.reconstructionErrorType.type = 'categorical';
                case 'LSTM'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'predictive';
                    modelOptions.dataType = 2;

                    modelOptions.hyperparameters.hiddenUnits.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.hiddenUnits.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                case 'Hybrid CNN-LSTM'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'predictive';
                    modelOptions.dataType = 2;

                    modelOptions.hyperparameters.hiddenUnits.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.hiddenUnits.type = 'integer';
                    modelOptions.hyperparameters.filter.value = app.ModelHyperparameter_2_EditField.Value;
                    modelOptions.hyperparameters.filter.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                case 'GRU'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'predictive';
                    modelOptions.dataType = 2;

                    modelOptions.hyperparameters.hiddenUnits.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.hiddenUnits.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                case 'CNN (DeepAnT)'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'predictive';
                    modelOptions.dataType = 3;

                    modelOptions.hyperparameters.filter.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.filter.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                case 'ResNet'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'predictive';
                    modelOptions.dataType = 3;

                    modelOptions.hyperparameters.filter.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.filter.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
                case 'MLP'
                    modelOptions.isMultivariate = app.IsMultivariateCheckBox.Value;
                    modelOptions.requiresPriorTraining = true;
                    modelOptions.calcThresholdsOn = "anomalous-validation-data";
                    modelOptions.outputsLabels = false;
                    modelOptions.modelType = 'predictive';
                    modelOptions.dataType = 1;
                    
                    modelOptions.hyperparameters.neurons.value = app.ModelHyperparameter_1_EditField.Value;
                    modelOptions.hyperparameters.neurons.type = 'integer';
                    modelOptions.hyperparameters.windowSize.value = app.WindowSizeEditField.Value;
                    modelOptions.hyperparameters.windowSize.type = 'integer';
                    modelOptions.hyperparameters.stepSize.value = app.StepSizeEditField.Value;
                    modelOptions.hyperparameters.stepSize.type = 'integer';
                    modelOptions.hyperparameters.epochs.value = app.EpochsEditField.Value;
                    modelOptions.hyperparameters.epochs.type = 'integer';
                    modelOptions.hyperparameters.minibatchSize.value = app.MinibatchSizeEditField.Value;
                    modelOptions.hyperparameters.minibatchSize.type = 'integer';
                    modelOptions.hyperparameters.learningRate.value = app.LearningRateEditField.Value;
                    modelOptions.hyperparameters.learningRate.type = 'real';
        
                    if app.UseValDataCheckBox.Value
                        modelOptions.hyperparameters.ratioTrainVal.value = app.RatioEditField.Value;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    else
                        modelOptions.hyperparameters.ratioTrainVal.value = 0;
                        modelOptions.hyperparameters.ratioTrainVal.type = 'real';
                    end
                    modelOptions.hyperparameters.scoringFunction.value = app.ScoringFunctionDropDown.Value;
                    modelOptions.hyperparameters.scoringFunction.type = 'categorical';
            end
            
            app.callingApp.addModelToList(modelOptions);
        end

        % Value changed function: ModelSelectionDropDown
        function ModelSelectionDropDownValueChanged(app, event)
            value = app.ModelSelectionDropDown.Value;

            app.resetHyperparameters();
            app.showModelHyperparametersFor(value);
        end

        % Value changed function: UseValDataCheckBox
        function UseValDataCheckBoxValueChanged(app, event)
            value = app.UseValDataCheckBox.Value;
            if value
                app.RatioEditField.Enable = 'on';
                app.RatioLabel.Enable = 'on';
            else
                app.RatioEditField.Enable = 'off';
                app.RatioLabel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddModelPanel = uipanel(app.UIFigure);
            app.DataAndTrainingHyperparametersPanel = uipanel(app.AddModelPanel);
            app.DataAndTrainingHyperparametersPanel.Title = 'Data and Training Hyperparameters';
            app.UseValDataCheckBox = uicheckbox(app.DataAndTrainingHyperparametersPanel);
            app.UseValDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseValDataCheckBoxValueChanged, true);
            app.UseValDataCheckBox.Text = 'Use Val Data';
            app.WindowsizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.WindowsizeLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.WindowSizeEditField.Limits = [1 Inf];
            app.WindowSizeEditField.Value = 100;
            app.StepsizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.StepsizeLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.Value = 1;
            app.EpochsLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.EpochsLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.EpochsEditField.Limits = [1 Inf];
            app.EpochsEditField.Value = 50;
            app.MinibatchSizeLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.MinibatchSizeLabel.Text = 'Minibatch Size';
            app.MinibatchSizeEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.MinibatchSizeEditField.Limits = [1 Inf];
            app.MinibatchSizeEditField.Value = 64;
            app.RatioEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.RatioEditField.Limits = [0 1];
            app.RatioEditField.Enable = 'off';
            app.RatioEditField.Value = 0.2;
            app.RatioLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.RatioLabel.Enable = 'off';
            app.RatioLabel.Text = 'Ratio';
            app.LearningRateEditFieldLabel = uilabel(app.DataAndTrainingHyperparametersPanel);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.DataAndTrainingHyperparametersPanel, 'numeric');
            app.LearningRateEditField.Limits = [0 1];
            app.LearningRateEditField.Value = 0.001;
            app.ModelHyperparametersPanel = uipanel(app.AddModelPanel);
            app.ModelHyperparametersPanel.Title = 'Model Hyperparameters';
            app.ModelHyperparameter_1_EditField = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelHyperparameter_1_EditField.Limits = [1 Inf];
            app.ModelHyperparameter_1_EditField.Value = 32;
            app.ModelHyperparameter_2_EditField = uieditfield(app.ModelHyperparametersPanel, 'numeric');
            app.ModelHyperparameter_2_EditField.Limits = [1 Inf];
            app.ModelHyperparameter_2_EditField.Visible = 'off';
            app.ModelHyperparameter_2_EditField.Value = 32;
            app.ModelHyperparameter_1_Label = uilabel(app.ModelHyperparametersPanel);
            app.ModelHyperparameter_1_Label.Text = 'Neurons';
            app.ModelHyperparameter_2_Label = uilabel(app.ModelHyperparametersPanel);
            app.ModelHyperparameter_2_Label.Visible = 'off';
            app.ModelHyperparameter_2_Label.Text = 'Filter';
            app.AddToModelSelectionButton = uibutton(app.AddModelPanel, 'push');
            app.AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddToModelSelectionButtonPushed, true);
            app.AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.ModelLabel = uilabel(app.AddModelPanel);
            app.ModelLabel.Text = 'Model';
            app.ModelSelectionDropDown = uidropdown(app.AddModelPanel);
            app.ModelSelectionDropDown.Items = {'FC AE', 'LSTM (reconstruction)', 'Hybrid CNN-LSTM (reconstruction)', 'TCN AE', 'LSTM', 'Hybrid CNN-LSTM', 'GRU', 'CNN (DeepAnT)', 'ResNet', 'MLP'};
            app.ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSelectionDropDownValueChanged, true);
            app.ModelSelectionDropDown.Value = 'FC AE';
            app.ScoringFunctionLabel = uilabel(app.AddModelPanel);
            app.ScoringFunctionLabel.Text = 'Scoring Function';
            app.ScoringFunctionDropDown = uidropdown(app.AddModelPanel);
            app.ScoringFunctionDropDown.Items = {'aggregated-errors', 'channelwise-errors', 'gauss', 'aggregated-gauss', 'channelwise-gauss'};
            app.ScoringFunctionDropDown.ItemsData = {'aggregated-errors', 'channelwise-errors', 'gauss', 'aggregated-gauss', 'channelwise-gauss'};
            app.ScoringFunctionDropDown.Tooltip = {'The scoring function determines how the anomaly scores are calculated'};
            app.ScoringFunctionDropDown.Value = 'aggregated-errors';
            app.IsMultivariateCheckBox = uicheckbox(app.AddModelPanel);
            app.IsMultivariateCheckBox.Tooltip = {'If unchecked, a separate model will be trained for each channel of the dataset (If the dataset is multivariate, otherwise this button doesn''t do anything)'};
            app.IsMultivariateCheckBox.Text = 'Is Multivariate';
            app.IsMultivariateCheckBox.Value = true;
            app.ReconstructionErrorTypeDropDown = uidropdown(app.AddModelPanel);
            app.ReconstructionErrorTypeDropDown.Items = {'median point-wise values', 'median point-wise errors', 'mean subsequence errors'};
            app.ReconstructionErrorTypeDropDown.Tooltip = {'This defines how overlapping reconstructed values are combined to produce a single value per time-point'};
            app.ReconstructionErrorTypeDropDown.Value = 'median point-wise values';
            app.ReconstructionErrorTypeLabel = uilabel(app.AddModelPanel);
            app.ReconstructionErrorTypeLabel.Text = {'Reconstruction'; 'Error Type'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection_DNN(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end