classdef OptimizationConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADOptimizationWindowUIFigure  matlab.ui.Figure
        ThresholdDropDown               matlab.ui.control.DropDown
        ThresholdLabel                  matlab.ui.control.Label
        MetricToOptimizeDropDown        matlab.ui.control.DropDown
        MetrictoOptimizeDropDownLabel   matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        OpenOptimizationConfigButton    matlab.ui.control.Button
        RunOptimizationButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        modelIDs        % IDs of models to optimize
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, modelIDs)
            app.callingApp = mainApp;
            app.modelIDs = modelIDs;

            app.MetricToOptimizeDropDown.Items = METRIC_NAMES;
            app.ThresholdDropDown.Items = THRESHOLD_NAMES;
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            iterations = app.IterationsEditField.Value;
            metric = app.MetricToOptimizeDropDown.Value;
            threshold = app.ThresholdDropDown.Value;

            app.callingApp.optimizeModels(app.modelIDs, iterations, metric, threshold);
            app.delete();
        end

        % Button pushed function: OpenOptimizationConfigButton
        function OpenOptimizationConfigButtonPushed(app, event)
            open TSADConfig_optimization.json
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.callingApp.closeProgressDialog();
            app.delete();
        end

        % Close request function: TSADOptimizationWindowUIFigure
        function TSADOptimizationWindowUIFigureCloseRequest(app, event)
            app.callingApp.closeProgressDialog();
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADOptimizationWindowUIFigure = uifigure('Visible', 'off');
            app.TSADOptimizationWindowUIFigure.Name = 'TSAD - Optimization Window';
            app.TSADOptimizationWindowUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.TSADOptimizationWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @TSADOptimizationWindowUIFigureCloseRequest, true);
            app.RunOptimizationButton = uibutton(app.TSADOptimizationWindowUIFigure, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.OpenOptimizationConfigButton = uibutton(app.TSADOptimizationWindowUIFigure, 'push');
            app.OpenOptimizationConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenOptimizationConfigButtonPushed, true);
            app.OpenOptimizationConfigButton.Text = 'Open Optimization Config';
            app.CancelButton = uibutton(app.TSADOptimizationWindowUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.IterationsEditFieldLabel = uilabel(app.TSADOptimizationWindowUIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.TSADOptimizationWindowUIFigure, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 15;
            app.MetrictoOptimizeDropDownLabel = uilabel(app.TSADOptimizationWindowUIFigure);
            app.MetrictoOptimizeDropDownLabel.Text = 'Metric to Optimize:';
            app.MetricToOptimizeDropDown = uidropdown(app.TSADOptimizationWindowUIFigure);
            app.MetricToOptimizeDropDown.Items = {};
            app.MetricToOptimizeDropDown.Value = {};
            app.ThresholdLabel = uilabel(app.TSADOptimizationWindowUIFigure);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdDropDown = uidropdown(app.TSADOptimizationWindowUIFigure);
            app.ThresholdDropDown.Items = {};
            app.ThresholdDropDown.Value = {};
            % Show the figure after all components are created
            app.TSADOptimizationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizationConfigurator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADOptimizationWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADOptimizationWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADOptimizationWindowUIFigure)
        end
    end
end