classdef OptimizationSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MainPanel              matlab.ui.container.Panel
        ExportLogdataCheckBox  matlab.ui.control.CheckBox
        SaveButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        exportLogdata
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, exportLogdata)
            app.callingApp = mainApp;
            app.exportLogdata = exportLogdata;
            app.ExportLogdataCheckBox.Value = exportLogdata;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            value = app.ExportLogdataCheckBox.Value;
            app.callingApp.updateExportLogdata(value);
            app.exportLogdata = value;
            app.SaveButton.Enable = 'off';
        end

        % Value changed function: ExportLogdataCheckBox
        function ExportLogdataCheckBoxValueChanged(app, event)
            value = app.ExportLogdataCheckBox.Value;
            if value ~= app.exportLogdata
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainPanel = uipanel(app.UIFigure);
            app.SaveButton = uibutton(app.MainPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ExportLogdataCheckBox = uicheckbox(app.MainPanel);
            app.ExportLogdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportLogdataCheckBoxValueChanged, true);
            app.ExportLogdataCheckBox.Text = 'Export Logdata';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizationSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end