classdef OptimizationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADOptimizationWindowUIFigure  matlab.ui.Figure
        OptimizationPanel               matlab.ui.container.Panel
        CancelButton                    matlab.ui.control.Button
        OpenOptimizationConfigButton    matlab.ui.control.Button
        TrainingPlotsCheckBox           matlab.ui.control.CheckBox
        ThresholdDropDown               matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        MetricToOptimizeDropDown        matlab.ui.control.DropDown
        MetrictoOptimizeDropDownLabel   matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        RunOptimizationButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        selection       % Either "all" or "selection"
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, selection, thresholds)
            app.callingApp = mainApp;
            app.selection = selection;

            items = getThresholdLabels(thresholds);
            app.ThresholdDropDown.Items = items;

            app.MetricToOptimizeDropDown.Items = METRIC_NAMES;
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            iterations = app.IterationsEditField.Value;
            metric = app.MetricToOptimizeDropDown.Value;
            threshold = getThresholdId(app.ThresholdDropDown.Value);
            if app.TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end

            app.callingApp.runOptimizationFor(iterations, metric, threshold, app.selection, trainingPlots);
        end

        % Button pushed function: OpenOptimizationConfigButton
        function OpenOptimizationConfigButtonPushed(app, event)
            open tsad_platform_config_optimization.json
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADOptimizationWindowUIFigure = uifigure('Visible', 'off');
            app.TSADOptimizationWindowUIFigure.Name = 'TSAD - Optimization Window';
            app.TSADOptimizationWindowUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.OptimizationPanel = uipanel(app.TSADOptimizationWindowUIFigure);
            app.RunOptimizationButton = uibutton(app.OptimizationPanel, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.IterationsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 15;
            app.MetrictoOptimizeDropDownLabel = uilabel(app.OptimizationPanel);
            app.MetrictoOptimizeDropDownLabel.Text = 'Metric to Optimize:';
            app.MetricToOptimizeDropDown = uidropdown(app.OptimizationPanel);
            app.MetricToOptimizeDropDown.Items = {};
            app.MetricToOptimizeDropDown.Value = {};
            app.ThresholdDropDownLabel = uilabel(app.OptimizationPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.ThresholdDropDown = uidropdown(app.OptimizationPanel);
            app.ThresholdDropDown.Items = {};
            app.ThresholdDropDown.Tooltip = {'The threshold used for converting anomaly scores into binary labels. To enable other thresholds, got to the Settings > Threshold Selection'};
            app.ThresholdDropDown.Value = {};
            app.TrainingPlotsCheckBox = uicheckbox(app.OptimizationPanel);
            app.TrainingPlotsCheckBox.Text = 'Training Plots';
            app.OpenOptimizationConfigButton = uibutton(app.OptimizationPanel, 'push');
            app.OpenOptimizationConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenOptimizationConfigButtonPushed, true);
            app.OpenOptimizationConfigButton.Text = 'Open Optimization Config';
            app.CancelButton = uibutton(app.OptimizationPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.TSADOptimizationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizationWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADOptimizationWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADOptimizationWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADOptimizationWindowUIFigure)
        end
    end
end