classdef ScoringFunctionSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADScoringFunctionSelectionUIFigure  matlab.ui.Figure
        ScoringFunctionPanel          matlab.ui.container.Panel
        CancelButton                  matlab.ui.control.Button
        ScoringFunctionDropDown       matlab.ui.control.DropDown
        ScoringFunctionDropDownLabel  matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, selection, thresholds)
            app.callingApp = mainApp;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.callingApp.updateScoringFunctionForSelection(app.ScoringFunctionDropDown.Value);
        end

        % Callback function
        function OpenOptimizationConfigButtonPushed(app, event)
            open tsad_platform_config_optimization.json
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADScoringFunctionSelectionUIFigure = uifigure('Visible', 'off');
            app.TSADScoringFunctionSelectionUIFigure.Name = 'TSAD - Scoring Function Selection';
            app.TSADScoringFunctionSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.ScoringFunctionPanel = uipanel(app.TSADScoringFunctionSelectionUIFigure);
            app.SaveButton = uibutton(app.ScoringFunctionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ScoringFunctionDropDownLabel = uilabel(app.ScoringFunctionPanel);
            app.ScoringFunctionDropDownLabel.Text = 'Scoring Function:';
            app.ScoringFunctionDropDown = uidropdown(app.ScoringFunctionPanel);
            app.ScoringFunctionDropDown.Items = {'Errors (aggregated)', 'Errors (channel-wise)', 'Gauss', 'Gauss (aggregated)', 'Gauss (channel-wise)', 'EWMA'};
            app.ScoringFunctionDropDown.Value = 'Errors (aggregated)';
            app.CancelButton = uibutton(app.ScoringFunctionPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.TSADScoringFunctionSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoringFunctionSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADScoringFunctionSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADScoringFunctionSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADScoringFunctionSelectionUIFigure)
        end
    end
end