classdef ThresholdSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADThresholdSelectionUIFigure  matlab.ui.Figure
        MainPanel                       matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        BestF1ScorepointwiseNode        matlab.ui.container.TreeNode
        BestF1ScoreeventwiseNode        matlab.ui.container.TreeNode
        BestF1ScorepointadjustedNode    matlab.ui.container.TreeNode
        BestF1ScorecompositeNode        matlab.ui.container.TreeNode
        TopkNode                        matlab.ui.container.TreeNode
        Mean3StdNode                    matlab.ui.container.TreeNode
        Mean3StdTrainNode               matlab.ui.container.TreeNode
        MaxTrainAnomalyScoreNode        matlab.ui.container.TreeNode
        PointFiveNode                   matlab.ui.container.TreeNode
        DynamicNode                     matlab.ui.container.TreeNode
        CustomNode                      matlab.ui.container.TreeNode
        SaveButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp      % The app that called this app
        thresholds
        selectedThresholds
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, thresholds)
            app.callingApp = mainApp;
            app.thresholds = thresholds;
            app.selectedThresholds = thresholds;

            nodesToBeChecked = [];

            if ismember("bestF1ScorePointwise", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorepointwiseNode];
            end
            if ismember("bestF1ScoreEventwise", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScoreeventwiseNode];
            end
            if ismember("bestF1ScorePointAdjusted", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorepointadjustedNode];
            end
            if ismember("bestF1ScoreComposite", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.BestF1ScorecompositeNode];
            end
            if ismember("topK", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.TopkNode];
            end
            if ismember("meanStd", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.Mean3StdNode];
            end
            if ismember("meanStdTrain", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.Mean3StdTrainNode];
            end
            if ismember("maxTrainAnomalyScore", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.MaxTrainAnomalyScoreNode];
            end
            if ismember("pointFive", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.PointFiveNode];
            end
            if ismember("dynamic", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.DynamicNode];
            end
            if ismember("custom", thresholds)
                nodesToBeChecked = [nodesToBeChecked, app.CustomNode];
            end

            app.Tree.CheckedNodes = nodesToBeChecked;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.callingApp.updateThresholds(app.selectedThresholds);
            app.thresholds = app.selectedThresholds;
            app.SaveButton.Enable = "off";
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            
            app.selectedThresholds = [];
            if ismember(app.BestF1ScorepointwiseNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "bestF1ScorePointwise"];
            end
            if ismember(app.BestF1ScoreeventwiseNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "bestF1ScoreEventwise"];
            end
            if ismember(app.BestF1ScorepointadjustedNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "bestF1ScorePointAdjusted"];
            end
            if ismember(app.BestF1ScorecompositeNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "bestF1ScoreComposite"];
            end
            if ismember(app.TopkNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "topK"];
            end
            if ismember(app.Mean3StdNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "meanStd"];
            end
            if ismember(app.Mean3StdTrainNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "meanStdTrain"];
            end
            if ismember(app.MaxTrainAnomalyScoreNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "maxTrainAnomalyScore"];
            end
            if ismember(app.PointFiveNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "pointFive"];
            end
            if ismember(app.DynamicNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "dynamic"];
            end
            if ismember(app.CustomNode, checkedNodes)
                app.selectedThresholds = [app.selectedThresholds, "custom"];
            end

            nodes = app.Tree.CheckedNodes;
            if numel(nodes) == 0
                app.SaveButton.Enable = "off";
            else            
                flag = false;
                for i = 1:numel(app.selectedThresholds)
                    if ~ismember(app.selectedThresholds(i), app.thresholds)
                        flag = true;
                    end
                end

                for i = 1:numel(app.thresholds)
                    if ~ismember(app.thresholds(i), app.selectedThresholds)
                        flag = true;
                    end
                end

                if flag
                    app.SaveButton.Enable = "on";
                else
                    app.SaveButton.Enable = "off";
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADThresholdSelectionUIFigure = uifigure('Visible', 'off');
            app.TSADThresholdSelectionUIFigure.Name = 'TSAD - Threshold Selection';
            app.TSADThresholdSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.MainPanel = uipanel(app.TSADThresholdSelectionUIFigure);
            app.SaveButton = uibutton(app.MainPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.Tree = uitree(app.MainPanel, 'checkbox');
            app.BestF1ScorepointwiseNode = uitreenode(app.Tree);
            app.BestF1ScorepointwiseNode.Text = 'Best F1 Score (point-wise)';
            app.BestF1ScoreeventwiseNode = uitreenode(app.Tree);
            app.BestF1ScoreeventwiseNode.Text = 'Best F1 Score (event-wise)';
            app.BestF1ScorepointadjustedNode = uitreenode(app.Tree);
            app.BestF1ScorepointadjustedNode.Text = 'Best F1 Score (point-adjusted)';
            app.BestF1ScorecompositeNode = uitreenode(app.Tree);
            app.BestF1ScorecompositeNode.Text = 'Best F1 Score (composite)';
            app.TopkNode = uitreenode(app.Tree);
            app.TopkNode.Text = 'Top k';
            app.Mean3StdNode = uitreenode(app.Tree);
            app.Mean3StdNode.Text = 'Mean + 3 * Std';
            app.Mean3StdTrainNode = uitreenode(app.Tree);
            app.Mean3StdTrainNode.Text = 'Mean + 3 * Std (Train)';
            app.MaxTrainAnomalyScoreNode = uitreenode(app.Tree);
            app.MaxTrainAnomalyScoreNode.Text = 'Max Train Anomaly Score';
            app.PointFiveNode = uitreenode(app.Tree);
            app.PointFiveNode.Text = '0.5';
            app.DynamicNode = uitreenode(app.Tree);
            app.DynamicNode.Text = 'Dynamic';
            app.CustomNode = uitreenode(app.Tree);
            app.CustomNode.Text = 'Custom';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.CloseButton = uibutton(app.MainPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.TSADThresholdSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADThresholdSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADThresholdSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADThresholdSelectionUIFigure)
        end
    end
end