classdef VisualizationData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AR_visualization_panel          matlab.ui.container.Panel
        AR_SelectedFileTestDropDown     matlab.ui.control.DropDown
        SelectedFileLabel_2             matlab.ui.control.Label
        AR_SelectedChannelTestDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_2          matlab.ui.control.Label
        AR_SelectedFileTrainDropDown    matlab.ui.control.DropDown
        SelectedFileLabel               matlab.ui.control.Label
        AR_SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel            matlab.ui.control.Label
        AR_RawTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        AR_RawTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% App Status Information        
        
        info_numTrainFiles = 0;             % Number of training files
        info_numTestFiles = 0;              % Number of all testing files
        info_numChannels = 0;           % Number of channels in the dataset
        
        channelNames

        dataTrainRaw
        timestampsTrain
        labelsTrain
        filesTrain
        dataTestRaw
        allTestTimestamps
        allTestLabels
        allTestFiles
        augmentation
        intensity
        augmentated
        training


    end
    
    methods (Access = private)
        
        function plotLoadedData(app, dataset, file_idx, channel_idx)
            switch dataset
                case 'train-raw'
                    axes = app.AR_RawTrainingDatasetPreviewUIAxes;
                    data = app.dataTrainRaw{file_idx, 1}(:, channel_idx);
                case 'test-raw'
                    axes = app.AR_RawTestingDatasetPreviewUIAxes;
                    data = app.dataTestRaw{file_idx, 1}(:, channel_idx);
                
            end
            
            cla(axes);
            plot(axes, data);
        end


        function createPreviewLegend(app, dataset, file_idx)
            switch dataset
                case 'train-raw'
                    axes = app.AR_RawTrainingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTrain{file_idx, 1});
                case 'test-raw'
                    axes = app.AR_RawTestingDatasetPreviewUIAxes;
                    indices = getIndexes(app.allTestLabels{file_idx, 1});
               
            end
            
            YLims = get(axes, 'YLim');
            limits = [];
            for i = 1:size(indices, 2)
                limits(1,i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(indices) && ~isempty(limits)
                patch(axes, indices, limits, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end 

        function resetAllData(app)
            app.dataTrainRaw = [];
            app.dataTestRaw = [];
            app.timestampsTrain = [];  
            app.allTestTimestamps = [];
            app.labelsTrain = [];  
            app.allTestLabels = [];
            app.filesTrain = [];            
            app.allTestFiles = [];
        end

        function updatePreviewData(app, dataset)
            switch dataset
                case 'train-raw'
                    file = app.AR_SelectedFileTrainDropDown.Value;
                    [~, file_idx] = ismember(file, app.AR_SelectedFileTrainDropDown.Items);
                    channel = app.AR_SelectedChannelTrainDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.AR_SelectedChannelTrainDropDown.Items);
                case 'test-raw'
                    file = app.AR_SelectedFileTestDropDown.Value;
                    [~, file_idx] = ismember(file, app.AR_SelectedFileTestDropDown.Items);
                    channel = app.AR_SelectedChannelTestDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.AR_SelectedChannelTestDropDown.Items);
             
            end

            app.plotLoadedData(dataset, file_idx, channel_idx);
            app.createPreviewLegend(dataset, file_idx);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path, augmentation, intensity, training)
            
            app.augmentation=augmentation;
            app.intensity=intensity;
            app.training=training;
        
            
            cla(app.AR_RawTrainingDatasetPreviewUIAxes);
            cla(app.AR_RawTestingDatasetPreviewUIAxes);
            
            app.resetAllData();          
            
            try
                [app.dataTrainRaw, ...
                    app.timestampsTrain, ...
                    app.labelsTrain, ...
                    app.filesTrain, ...
                    app.dataTestRaw, ...
                    app.allTestTimestamps, ...
                    app.allTestLabels, ...
                    app.allTestFiles, ...
                    app.channelNames] = loadCustomDataset(path);
            catch
                uialert(app.UIFigure, ['No testing or testing data found! Please make make sure the selected folder contains' ...
                    'the following directories: train, test. The folders should include .csv files with fields:' ...
                    'timestamp, value1, value2, ..., is_anomaly'], ...
                            'Data Error', Icon='error');
                return;
            end

            if (app.augmentated)
                [app.dataTrainRaw, app.dataTestRaw] = augmentationData(app.dataTrainRaw, app.dataTestRaw, app.augmentation,app.intensity,app.training);
            end


            if ~isempty(app.dataTrainRaw)
                app.plotLoadedData('train-raw', 1, 1);
                app.createPreviewLegend('train-raw', 1);

                app.info_numChannels = size(app.dataTrainRaw{1, 1}, 2);

                app.AR_SelectedFileTrainDropDown.Items = app.filesTrain;
            end

            if ~isempty(app.dataTestRaw)
                app.plotLoadedData('test-raw', 1, 1);
                app.createPreviewLegend('test-raw', 1);

                app.info_numChannels = size(app.dataTestRaw{1, 1}, 2);

                app.AR_SelectedFileTestDropDown.Items = app.allTestFiles;
            end

            app.AR_SelectedChannelTestDropDown.Items = app.channelNames;
            app.AR_SelectedChannelTrainDropDown.Items = app.channelNames;
            
        end

        % Value changed function: AR_SelectedFileTrainDropDown
        function AR_SelectedFileTrainDropDownValueChanged(app, event)
            
            app.updatePreviewData('train-raw');
        end

        % Value changed function: AR_SelectedFileTestDropDown
        function AR_SelectedFileTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
            
        end

        % Value changed function: AR_SelectedChannelTrainDropDown
        function AR_SelectedChannelTrainDropDownValueChanged(app, event)
        
            app.updatePreviewData('train-raw');
        end

        % Value changed function: AR_SelectedChannelTestDropDown
        function AR_SelectedChannelTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AR_visualization_panel = uipanel(app.UIFigure);
            app.AR_RawTestingDatasetPreviewUIAxes = uiaxes(app.AR_visualization_panel);
            title(app.AR_RawTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (raw)')
            xlabel(app.AR_RawTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.AR_RawTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.AR_RawTestingDatasetPreviewUIAxes, 'Z')
            app.AR_RawTrainingDatasetPreviewUIAxes = uiaxes(app.AR_visualization_panel);
            title(app.AR_RawTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (raw)')
            xlabel(app.AR_RawTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.AR_RawTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.AR_RawTrainingDatasetPreviewUIAxes, 'Z')
            app.SelectedChannelLabel = uilabel(app.AR_visualization_panel);
            app.SelectedChannelLabel.Text = 'Selected Channel:';
            app.AR_SelectedChannelTrainDropDown = uidropdown(app.AR_visualization_panel);
            app.AR_SelectedChannelTrainDropDown.Items = {};
            app.AR_SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @AR_SelectedChannelTrainDropDownValueChanged, true);
            app.AR_SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel = uilabel(app.AR_visualization_panel);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.AR_SelectedFileTrainDropDown = uidropdown(app.AR_visualization_panel);
            app.AR_SelectedFileTrainDropDown.Items = {};
            app.AR_SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @AR_SelectedFileTrainDropDownValueChanged, true);
            app.AR_SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_2 = uilabel(app.AR_visualization_panel);
            app.SelectedChannelLabel_2.Text = 'Selected Channel:';
            app.AR_SelectedChannelTestDropDown = uidropdown(app.AR_visualization_panel);
            app.AR_SelectedChannelTestDropDown.Items = {};
            app.AR_SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @AR_SelectedChannelTestDropDownValueChanged, true);
            app.AR_SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_2 = uilabel(app.AR_visualization_panel);
            app.SelectedFileLabel_2.Text = 'Selected File:';
            app.AR_SelectedFileTestDropDown = uidropdown(app.AR_visualization_panel);
            app.AR_SelectedFileTestDropDown.Items = {};
            app.AR_SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @AR_SelectedFileTestDropDownValueChanged, true);
            app.AR_SelectedFileTestDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizationData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end