classdef testpopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function saveNetPopup(app)            
            d = dialog("Name", "Save Network", "Position", [61 55 230 100]);
            movegui(d, "center")
            netName = uicontrol("Parent",d, ...
                "Style","edit", ...
                "Position",[111 64 100 22]);
            netNameTxt = uicontrol("Parent", d, ...
                "Style", "text", ...
                "Position",[13 64 83 22], ...
                "String", "Network name");
            checkBox = uicontrol("Parent",d, ...
                "Style","checkbox", ...
                "String", "Export net options", ...
                "Position", [13 43 119 22]);
            btn = uicontrol("Parent",d, ...
                "Style","pushbutton", ...
                "Position", [59 12 100 22], ...
                "String", "Save", ...
                "Callback", @btnPush)
            function btnPush(app)
                assignin("base", netName, app.net)
                if app.checkBox.Value == 1
                    assignin("base", netName+"_options", app.options)
                end
                disp('Variables saved!')
            end
        end
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                delete(app.UIFigure);
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, "center")
            saveNetPopup(app)            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             uiconfirm(app.UIFigure,'Close document?','Confirm Close', ...
%             'CloseFcn',@app.mycallback);
            fig = uifigure;
msg = 'Saving these changes will overwrite previous changes.';
title = 'Confirm Save';
selection = uiconfirm(fig,msg,title,...
           'Options',{'Overwrite','Save as new','Cancel'},...
           'DefaultOption',2,'CancelOption',3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testpopup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end